classdef SAGEv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        UPDATELOCALBFILESButton     matlab.ui.control.Button
        UPDATEMBARIODVButton        matlab.ui.control.Button
        UPDATEQCLISTFILEButton      matlab.ui.control.Button
        SHOWQCHISTORYButton         matlab.ui.control.Button
        SelectfileButton            matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        RAWTab                      matlab.ui.container.Tab
        UIAxes_RAWCycleData         matlab.ui.control.UIAxes
        UIAxes_RAWSdnData           matlab.ui.control.UIAxes
        UIAxes_RAWCycleAnom         matlab.ui.control.UIAxes
        UIAxes_RAWSdnAnom           matlab.ui.control.UIAxes
        UIAxes_Hidden4CBRAW         matlab.ui.control.UIAxes
        QCTab                       matlab.ui.container.Tab
        UIAxes_QCCycleData          matlab.ui.control.UIAxes
        UIAxes_QCSdnData            matlab.ui.control.UIAxes
        UIAxes_QCCycleAnom          matlab.ui.control.UIAxes
        UIAxes_QCSdnAnom            matlab.ui.control.UIAxes
        UIAxes_Hidden4CBQC          matlab.ui.control.UIAxes
        pHpumpoffsetTab             matlab.ui.container.Tab
        UIAxes_CyclePO              matlab.ui.control.UIAxes
        UIAxes_ProfNoCor            matlab.ui.control.UIAxes
        UIAxes_ProfPOCor            matlab.ui.control.UIAxes
        UIAxes_Hidden4CB            matlab.ui.control.UIAxes
        SAGEV2Panel                 matlab.ui.container.Panel
        pHCpOffsetCorrectionButtonGroup  matlab.ui.container.ButtonGroup
        noneButton                  matlab.ui.control.RadioButton
        polyButton                  matlab.ui.control.RadioButton
        linearButton                matlab.ui.control.RadioButton
        mixedButton                 matlab.ui.control.RadioButton
        DeleteRowButton             matlab.ui.control.Button
        InsertRowButton             matlab.ui.control.Button
        ShowmapButton               matlab.ui.control.Button
        ReloadinitialParameterdataButton  matlab.ui.control.Button
        DataAdjustmentsPanel        matlab.ui.container.Panel
        ApplyCelleditsButton        matlab.ui.control.Button
        CalculateAdjustmentsButton  matlab.ui.control.Button
        FindChangePointsButton      matlab.ui.control.Button
        QCATable                    matlab.ui.control.Table
        ParameterButtonGroup        matlab.ui.container.ButtonGroup
        NitrateButton               matlab.ui.control.RadioButton
        pHButton                    matlab.ui.control.RadioButton
        OxygenButton                matlab.ui.control.RadioButton
        TemperatureButton           matlab.ui.control.RadioButton
        SalinityButton              matlab.ui.control.RadioButton
        ReferenceDataButtonGroup    matlab.ui.container.ButtonGroup
        ESPER_MIXButton             matlab.ui.control.RadioButton
        ESPER_NNButton              matlab.ui.control.RadioButton
        ESPER_LIRButton             matlab.ui.control.RadioButton
        CANYONBButton               matlab.ui.control.RadioButton
        WOAButton                   matlab.ui.control.RadioButton
        ESPER_MIX_NoO2Button        matlab.ui.control.RadioButton
        PlotTypeButtonGroup         matlab.ui.container.ButtonGroup
        DeepButton                  matlab.ui.control.RadioButton
        SurfaceButton               matlab.ui.control.RadioButton
        ProfileButton               matlab.ui.control.RadioButton
        GlodapCrossoverkmPanel      matlab.ui.container.Panel
        XOver_km                    matlab.ui.control.NumericEditField
        EditField_5Label            matlab.ui.control.Label
        PressureRangedbarPanel      matlab.ui.container.Panel
        Pmax                        matlab.ui.control.NumericEditField
        EditField_4Label            matlab.ui.control.Label
        Pmin                        matlab.ui.control.NumericEditField
        EditField_3Label            matlab.ui.control.Label
        CycleRangePanel             matlab.ui.container.Panel
        CycleMax                    matlab.ui.control.NumericEditField
        EditField_2Label            matlab.ui.control.Label
        CycleMin                    matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        dirs       % structure of directory paths and file names
        fhdr       % float data colomn headers as a cell array from get_sageV2_float_data.m
        fdata      % float data matrix output using get_sageV2_float_data.m
                   %    for SPROF or ODV files. Adjusted data gets modified in
                   %    SageV2 based on QC adjustment matrix in the table
        fdata_orig % static version of fdata - never altered
        pdata      % fdata subset structure from GUI choices for plotting
        finfo      % info structure from float data structure
        fI         % column index for fdata matrix
        pH_PO      % pH pump offset structure w/ flds: hdr,data, pH, H_offset_type
        ref_data   % reference data structure with  data dim 1 = fdata dim 1
        QCA        % Original QC adjustments loaded from QC List file
        CGOD       % QC adjustments structure that are updated in GUI
        anom       % float - chosen ref, ie: anom.NO3.raw, anom.pH.adj
        glodap     % glodap ref data
        bottle     % bottle data assoc with float deployment cast if it exists
        state      % structure of parameter plotting states
        info       % misc meta info / logicals to carry along
        colors = struct()    % colors for GUI
        bad_sensors
        sage_log   % cell array of processing info
        OrigSize   % structure of orginal axes sizes by tab & GUI order
        cmaps      % structure of colormaps
        mlist      % mbari master processing list
        QC_history % a cell array of comment lines for float form QCList log file
        QC_info    % structure with parameter fields, each with QC info fields (Operator,Date, Type, Ref, PRange, NodeCount, BIC)
        tf_update_data % a logical specifying if adjusted data needs an update based on changes to the table data
        error_limits % error_limits used to make BIC favor fewer nodes and for manual correction determinations
        sig_level % signifigance level for testing calc adjustments slope different from zero
        uistyles % styles to color table elements with, defined in the startup
        tf_OK % A logical indicating a succesful data load
        defaults % default values for cell edit boxes difiened in SAGEv2_init.m and updated in appfunction init_states
    end
    
    methods (Access = private)

        function init_states(app) % use float data and other info to init

            % ***********************************************************
            % SET GUI DEFAULTS - IMPORTANT IF LOADING A NEW FLOAT IN SAME
            % GUI INSTANCE
            % ***********************************************************

            % SET EDIT BOX DEFAULTS
            app.CycleMin.Value = app.defaults.CycleMin;
            app.CycleMax.Value = app.defaults.CycleMax;
            app.Pmin.Value     = app.defaults.Pmin;
            app.Pmax.Value     = app.defaults.Pmax ;
            app.XOver_km.Value = app.defaults.XOver_km ;
            app.QCATable.Data  = app.defaults.QCATable ;

            % ENABLE ALL RADIO BUTTONS - some of this may be redundant
            RB = findobj(app.UIFigure,'Type','uiradiobutton');
            set(RB,'Enable','on'), clear RB
            RB = findobj(app.DataAdjustmentsPanel,'Type','uibutton');
            set(RB,'Enable','on'), clear RB

            % ENABLE QC ADJUSTMENT PANEL OBJECTS
            set(findall(app.DataAdjustmentsPanel, ...
                '-property', 'enable'), 'enable', 'on') % turn on panel
            app.ApplyCelleditsButton.Enable = 'off'; % except for this one!

            % ENABLE REFERENCE BUTTON GROUP BUTTONS
            set(findall(app.ReferenceDataButtonGroup, ...
                '-property', 'enable'), 'enable', 'on') % turn on panel


            % SET DEFAULT RADIO BUTTONS: Deep, ESPER_MIX, Nitrate
            % I've organized so 1st radio button is always default
            plot_objs = app.PlotTypeButtonGroup.Children; % PlotType
            app.PlotTypeButtonGroup.SelectedObject = plot_objs(1);

            ref_objs = app.ReferenceDataButtonGroup.Children; %ReferenceData uibuttons
            if app.finfo.tf_Oxygen == 1
                app.ReferenceDataButtonGroup.SelectedObject = ref_objs(1);
            else
                app.ReferenceDataButtonGroup.SelectedObject = app.WOAButton;
                tg = ~cellfun(@isempty, regexp({ref_objs.Text}, 'WOA|NoO2','once'));
                for ct = 1:numel(tg) % if no O2, diable refs requireing O2
                    ref_objs(ct).Enable = tg(ct);
                end
                clear tg ct              
            end

            param_objs = app.ParameterButtonGroup.Children; %Parameters
               app.ParameterButtonGroup.SelectedObject = param_objs(1);

            offset_objs = app.pHCpOffsetCorrectionButtonGroup.Children; %Parameters
               app.pHCpOffsetCorrectionButtonGroup.SelectedObject = offset_objs(1); % none


            % NEXT USE LOADED FLOAT DATA TO FINE TUNE CYCLE  AND PRESSURE
            % RANGES IF DEFAULTS AREN'T PERFECT
            app.CycleMin.Value = app.finfo.CycleRange(1);
            app.CycleMax.Value = app.finfo.CycleRange(2);

            % adjust press range to float max mins for deep range if necessary
            if app.finfo.PmaxRange(2) < app.Pmax.Value
                fprintf('Adjusting Pmax to deep float range\n');
                app.Pmax.Value = app.finfo.PmaxRange(2);
            end
            if app.finfo.PmaxRange(1) < app.Pmin.Value
                fprintf('Adjusting Pmin to deep float range\n');
                app.Pmin.Value = app.finfo.PmaxRange(1);
            end

            % CHECK IF FLOAT IS MISSSING NO3 or pH SENSOR or ALL BAD DATA
            if app.finfo.tf_Nitrate == 0
                app.NitrateButton.Enable = 'off';
                app.ParameterButtonGroup.SelectedObject = app.pHButton;
            else
                app.pHCpOffsetCorrectionButtonGroup.Enable = 'off';
            end

            if app.finfo.tf_pH == 0
                app.pHButton.Enable = 'off';
                app.pHCpOffsetCorrectionButtonGroup.Enable = 'off';
            end

            if app.finfo.tf_Oxygen == 0
                app.OxygenButton.Enable = 'off';
            end

            % LOAD QC LIST FILE AND POPULATE TABLE WITH THE DATA
            % dirs.QClists is set in the stand alone function sageV2_init()
            qclist_fn = sprintf('%s_FloatQCList.txt',app.finfo.wmo);
            qclist_fp = fullfile(app.dirs.QClists, qclist_fn);   

            if isfile(qclist_fp)
                app.QCA  = get_QCAv2(qclist_fp);
                fprintf('QC list file loaded into SageV2 for %s\n', ...
                    app.finfo.wmo);
            else % NO QC LIST YET!
                app.QCA.Oxygen    = [1 1 0 0]; % set as empty to start
                app.QCA.Nitrate   = [1 1 0 0]; 
                app.QCA.pH        = [1 1 0 0]; 
                app.QCA.pH_offset = 'none';
                app.QCA.QC_info   = [];
            end
            app.QC_info.qclist_fp = qclist_fp; % use when updating
            app.CGOD          = app.QCA; % this one gets modified
            param             = app.ParameterButtonGroup.SelectedObject.Text;
            app.QCATable.Data = app.CGOD.(param); % populate data table

            % DETERMINE pH PUMP OFFSET CORRECTION STATUS
            % If no pH data or CpAct data, no correction possible
            % If avaialable check pump offset correction type and adjust
            % accordingly

            if app.pH_PO.tf == 0
                app.pHCpOffsetCorrectionButtonGroup.Enable = 0;
                fprintf(['No pH pump offset correction possible - ', ...
                    'turning off panel\n']);
                % turn off all pH pump offset tab objects
                set(findall(app.pHpumpoffsetTab, '-property', 'enable'), ...
                    'enable', 'off')
            elseif ~strcmp(app.CGOD.pH_offset,'none') % update pH raw with offset corrected     
                %Find which column contains the correct offset data based on the method specified in CGOD
                tf_col = contains(app.pH_PO.hdr, app.CGOD.pH_offset);
                
                %Replace the pH data with the adjusted data
                app.fdata(:,app.fI.PH) = app.pH_PO.data(:,tf_col); % adj should be OK
                fprintf(['Raw pH was replaced with offset corrected pH ', ...
                    'using the %s estimate.\n'], app.CGOD.pH_offset);

                % shift to offset button used in previous QC
                app.pHCpOffsetCorrectionButtonGroup.SelectedObject = ...
                    app.([app.CGOD.pH_offset,'Button']);
            end          

            if app.QCA.Oxygen(2) == 1
                alert_cell = {['The oxygen gain appears to equal the ', ...
                    'default value of 1!. Please ensure oxygen has been ',...
                    'corrected before using sagev2.'], 'WARNING!'};
                uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
            end

            % IF MBARI FLOAT TRY & GET MBARI_ID
            app.finfo.MBARI_ID = '';
            if isfield(app.mlist,'list')
                t1 = strcmp(app.mlist.list(:,3), app.finfo.wmo); % col3=wmo
                if any(t1)
                    app.finfo.MBARI_ID = app.mlist.list{t1,1}; %col1 = mbari id
                end
            end


            % TRY AND GET BOTTLE DATA FOR DEPLOYMENT PROFILE];
            if isfield(app.bottle, 'lookup') && ~isempty(app.bottle.lookup) % lookup table exists
                % play this reset game to clear any fields from previous float
                tmp               = app.bottle.lookup; 
                app.bottle        =[];
                app.bottle.lookup = tmp;
                
                % Check if mlist contains data before attempting array indexing
                if isprop(app, 'mlist') && isfield(app.mlist, 'list') && ~isempty(app.mlist.list)
                    t1 = strcmp(app.mlist.list(:,3), app.finfo.wmo); % col3=wmo
                else
                    t1 = false; % Fallback when mlist is unpopulated
                end % wmo match
                if sum(t1) == 1
                    lookup_ln = app.bottle.lookup(t1,:);
                    bottle_fp = fullfile(app.dirs.bottle, lookup_ln{1,7});
                    %bdata     = get_shipboard_dataV2(bottle_fp);
                    bdata     = get_shipboard_data(bottle_fp);
                    fprintf('Bottle data file found for %s\n',app.finfo.wmo);

                    % Next subset to station and cast
                    t1 = bdata.data(:,strcmp(bdata.hdr,'STNNBR')) == lookup_ln{1,5} ...
                        & bdata.data(:,strcmp(bdata.hdr,'CASTNO')) == lookup_ln{1,6};

                    if any(t1)
                        bdata.data        = bdata.data(t1,:);
                        app.bottle.hdr    = bdata.hdr;
                        app.bottle.data   = bdata.data;
                        app.bottle.cruise = bdata.cruise;
                        app.bottle.expo   = bdata.expocode;
                        str = sprintf('Loading bottle data ...');
                        app.SelectfileButton.Text = str;
                        drawnow
                    else
                        fprintf(['No 1st cast bottle data found for ', ...
                            '%s at station %d and cast %d\n'], ...
                            app.finfo.wmo, lookup_ln{1,5}, lookup_ln{1,6});
                    end
                else
                    fprintf(['No bottle data file for %s exists on the ',...
                        'lookup table.\n'],app.finfo.wmo);
                end
            end


            % ************************************************************
            % BUILD PARAMETER STATE STRUCTURES
            % these will be used to "remember" param plot settings
            params    = {app.ParameterButtonGroup.Children.Text};
            plottypes = {app.PlotTypeButtonGroup.Children.Text};

            % Build skeleton then add for each param
            X.cycles  = [app.CycleMin.Value  app.CycleMax.Value];
            for ct = 1:length(plottypes)
                if contains(plottypes{ct},'surf','IgnoreCase',true)
                    X.Surface = [0 30];
                    if app.finfo.PminRange(2) > X.Surface(2)
                        X.Surface(2) = app.finfo.PminRange(2);
                    end
                elseif contains(plottypes{ct},'deep','IgnoreCase',true)
                    X.Deep    = [app.Pmin.Value, app.Pmax.Value];
                elseif contains(plottypes{ct},'prof','IgnoreCase',true)
                    X.Profile = [0 app.finfo.PmaxRange(2)];
                else
                    fprintf(['PlotType (%s) is not recognized. Please ',...
                        'add code to adapapt!\n'], plottypes{ct})
                end
            end
            X.Ref     = app.ReferenceDataButtonGroup.SelectedObject.Text;
            %X.CGOD    = []; % leave empty to start - correction matrix
            for ct = 1:length(params)
                app.state.(params{ct}) = X; % step through params to make skeletons
            end
            clear X params ct plottypes 
           
            % Build QC_info stucture skeletons to hold QC particulars for
            % NO3 & pH (UserName, Date, QC_Type, Ref, PresRange, NodeCount, BIC, RMSE, etc)
            % IF available could fill with defaults parsed in get_QCAv2 ???
            X.UserName  = java.lang.System.getProperty('user.name'); % should work for all platforms
            X.Date      = '???Date???';
            X.Ref       = '???Ref???';
            X.PresRange = [NaN NaN];
            X.NodeCt    = NaN;
            X.BIC       = NaN;
            X.RMSE      = NaN;

            if ~isfield(app.CGOD.QC_info,'Nitrate')
                app.QC_info.Nitrate = X;
            else
                app.QC_info.Nitrate = app.CGOD.QC_info.Nitrate;
            end
            
            if ~isfield(app.CGOD.QC_info,'pH')
                app.QC_info.pH      = X;
            else
                app.QC_info.pH= app.CGOD.QC_info.pH;
            end


            clear X

            % % CHECK IF FLOAT IS MISSSING NO3 or pH SENSOR or ALL BAD DATA
            % if app.finfo.tf_Nitrate == 0
            %     app.NitrateButton.Enable = 'off';
            %     app.ParameterButtonGroup.SelectedObject = app.pHButton;
            % else
            %     app.pHCpOffsetCorrectionButtonGroup.Enable = 'off';
            % end
            % 
            % if app.finfo.tf_pH == 0
            %     app.pHButton.Enable = 'off';
            %     app.pHCpOffsetCorrectionButtonGroup.Enable = 'off';
            % end
            % 
            % if app.finfo.tf_Oxygen == 0
            %     app.OxygenButton.Enable = 'off';
            % end


            % initialize pH pump offset data if warrented
            % Not related to whether or not the correction is applied
            if app.pH_PO.tf == 1
                pstate = app.state.pH;
                tP       = app.fdata(:,app.fI.P) >= pstate.Profile(1) & ...
                    app.fdata(:,app.fI.P) <= pstate.Profile(2);
                tC       = app.fdata(:,app.fI.STA) >= pstate.cycles(1) & ...
                    app.fdata(:,app.fI.STA) <= pstate.cycles(2);

                tQC = ~(app.fdata(:, app.fI.PH+1) == app.finfo.QFbad | ...
                    app.fdata(:, app.fI.PH+1) == app.finfo.QFmiss);

                tg    = tP & tC & tQC; % subset logical for P, cycle & QC flags
                app.pdata.PO_pH = app.pH_PO.data(tg,:); % profile data

                tgPO = app.pH_PO.POdata(:,1) >= pstate.cycles(1) & ...
                    app.pH_PO.POdata(:,1) <= pstate.cycles(2);
                app.pdata.PO_PO = app.pH_PO.POdata(tgPO,:);
            end

        end
        
        function update_anom(app)
            % update float - ref data anomalies for NO3 & pH
            % This function opperates on all the data not just the plot
            % subset which is determined in update_plot_data(app)
            % The "ref master" list in get_sageV2_ref_data must match the
            % text strings for the ref data button group buttons for the
            % data grab to work with dynamic fields. Also get cycle median
            % for corection node calcs.

            ref_str = app.ReferenceDataButtonGroup.SelectedObject.Text;

            % **** NITRATE *****
            iN   = [app.fI.N, app.fI.NA]; % NO3 indices for float data
            tmpN = app.fdata(:,iN) - app.ref_data.(ref_str).Nitrate;
            app.anom.Nitrate.raw  = tmpN(:,1);
            app.anom.Nitrate.adj  = tmpN(:,2);


            % ***** pH *****
            % no pH in WOA
            if ~strncmp(ref_str ,'WOA',3)  && app.finfo.tf_pH == 1
                iPH   = [app.fI.PH, app.fI.PHA]; % pH indices for float data

                tmpPH = app.fdata(:,iPH) - app.ref_data.(ref_str).pH;
                app.anom.pH.raw = tmpPH(:,1);
                app.anom.pH.adj = tmpPH(:,2);
            else
                app.anom.pH.raw  = tmpN(:,1)*NaN; % fill with NaN's for WOA
                app.anom.pH.adj  = tmpN(:,1)*NaN;
            end

            fprintf('Reference anomlies have been updated using %s\n', ...
                ref_str);
            clear iN tmpN  tmpPH iPH ref_str 
        end

        
        function update_glodap(app)
            app.glodap = [];
            % Safely determine the filename and folder for GLODAP reference data
            if isfield(app.dirs, 'glodap_fn') && ~isempty(app.dirs.glodap_fn)
                glodap_filename = app.dirs.glodap_fn;
            else
                glodap_filename = 'GLODAPv3_Merged_Master_File.mat'; % Default fallback
            end

            if isfield(app.dirs, 'glodap') && ~isempty(app.dirs.glodap)
                glodap_folder = app.dirs.glodap;
            else
                glodap_folder = fullfile('C:', 'Users', 'Jennifer.McWhorter', 'Documents', 'MATLAB', 'GLODAPv3');
            end

            glodap_fp = fullfile(glodap_folder, glodap_filename);

            % Verify file existence before loading
            if ~isfile(glodap_fp)
                warning('GLODAP reference file not found at: %s. Skipping GLODAP update.', glodap_fp);
                return;
            end
            app.glodap = get_GLODAPv2_local_SAGEv2(glodap_fp, ...
                app.finfo.ftrack(:,[1,2,4,3]), ... % lon,lat to lat,lon
                app.XOver_km.Value, [0 2000]);
            fprintf('Nearby GLODAP profiles have been grabbed within %0.0f km\n',...
                app.XOver_km.Value);

            % BUILD GLODPAP INDEX STRUCTURE & ASSIGN TO APP
            I.STA = find(strcmp('float cycle', app.glodap.hdr)   == 1);
            I.SDN = find(strcmp('Date', app.glodap.hdr)          == 1);
            I.P   = find(strcmp('G2pressure', app.glodap.hdr)    == 1);
            I.T   = find(strcmp('G2temperature', app.glodap.hdr) == 1);
            I.S   = find(strcmp('G2salinity', app.glodap.hdr)    == 1);
            I.O   = find(strcmp('G2oxygen', app.glodap.hdr)      == 1);
            I.N   = find(strcmp('G2nitrate', app.glodap.hdr)     == 1);
            I.PH  = find(strcmp('ph_insitu', app.glodap.hdr)     == 1);
            app.glodap.I = I;

            clear I 

        end
        

        function update_plot_data(app) 
            % Gather all data needed for plotting a given parameter & 
            % save to pdata stucture. called by update_plots. 

            PlotType = app.PlotTypeButtonGroup.SelectedObject.Text; %deep,surf,prof
            param    = app.ParameterButtonGroup.SelectedObject.Text;
            pstate   = app.state.(param); % state structure for given parameter, old values

            % get float data for plotting
            Iflds = fieldnames(app.fI); % index fieldnames
            tf    = ismember(Iflds,{'SDN' 'STA' 'LAT' 'LON' 'P'});
            Iflds = Iflds(~tf); % reduce to plotting indice fieldnames only
            tf    = ~cellfun(@isempty, regexpi(Iflds,['^',param(1)],'once')); % match 1st letter
            flds  =  Iflds(tf); % param float data indices or index fieldnames
            if isempty(flds)
                fprintf('app.fI.X ind was not resloved for %s\n',param)
            end

            tP       = app.fdata(:,app.fI.P) >= pstate.(PlotType)(1) & ...
                       app.fdata(:,app.fI.P) <= pstate.(PlotType)(2);
            tC       = app.fdata(:,app.fI.STA) >= pstate.cycles(1) & ...
                       app.fdata(:,app.fI.STA) <= pstate.cycles(2);           
            tQC      = true(size(tP));

            % bad flagging should be the same for raw,adj, raw PO so just use
            % first param index found
            if ~isempty(flds) 
                tQC = ~(app.fdata(:, app.fI.(flds{1})+1) == app.finfo.QFbad | ...
                    app.fdata(:, app.fI.(flds{1})+1) == app.finfo.QFmiss);
            end
            tg    = tP & tC & tQC; % subset logical for P, cycle & QC flags

            pd = app.fdata(tg,:); % subsetted float data


            % update pH pump offset correction data if all conditions are right
            % THIS IS FOR THE PUMP OFFSET TAB ONLY - MAY BE ABLE TO CLEAN
            % UP A BIT HERE
            if  app.pH_PO.tf == 1
                tPP   = app.fdata(:,app.fI.P) >= app.state.pH.Profile(1) & ...
                        app.fdata(:,app.fI.P) <= app.state.pH.Profile(2);
                tQCPH = ~(app.fdata(:,app.fI.PH+1) == app.finfo.QFbad | ...
                    app.fdata(:,app.fI.PH+1) == app.finfo.QFmiss);
                tgg   = tPP & tC & tQCPH; 

                app.pdata.PO_pH = app.pH_PO.data(tgg,:);

                tgPO = app.pH_PO.POdata(:,1) >= app.state.pH.cycles(1) & ...
                    app.pH_PO.POdata(:,1) <= app.state.pH.cycles(2);
                app.pdata.PO_PO = app.pH_PO.POdata(tgPO,:);
            end

            app.pdata.STA  = pd(:, app.fI.STA); % cycles
            app.pdata.SDN  = pd(:, app.fI.SDN); % Matlab SDN
            app.pdata.P    = pd(:, app.fI.P);   % pressure
            app.pdata.cgod = []; % default if no update (for T & S)
            
            % NO3,pH, O2 (ph can have 3 fields: raw, adj, raw pHPO
            % NOTE: Sprof bad adj data will be fill value...
            if length(flds) > 2 && app.ApplypHpumpoffsetCheckBox.Value == 1 
                app.pdata.RD = pd(:, app.fI.(flds{3})); % raw float pH PO corrected data
                app.pdata.AD = pd(:, app.fI.(flds{2})); % adjusted float data
            elseif length(flds) > 1 %
                app.pdata.RD = pd(:, app.fI.(flds{1})); % raw float data
                app.pdata.AD = pd(:, app.fI.(flds{2})); % adjusted float data
            elseif length(flds) == 1 % T,S
                app.pdata.RD = pd(:,app.fI.(flds{1}));
                app.pdata.AD = app.pdata.RD; % adj = raw
            end

            % get Ref & anomaly data subsets
            if strcmp(param,'Nitrate')
                app.pdata.REF = app.ref_data.(pstate.Ref).Nitrate(tg);
                app.pdata.RDA = app.anom.(param).raw(tg); %raw anomaly
                app.pdata.ADA = app.anom.(param).adj(tg); % adjusted anomaly
                app.pdata.cgod = app.CGOD.Nitrate;
            elseif strcmp(param,'pH') && ~strcmp(pstate.Ref,'WOA')
                app.pdata.REF = app.ref_data.(pstate.Ref).pH(tg);
                app.pdata.RDA = app.anom.(param).raw(tg);
                app.pdata.ADA = app.anom.(param).adj(tg);
                app.pdata.cgod = app.CGOD.pH;
            elseif strcmp(param,'Oxygen') 
                app.pdata.REF = NaN(sum(tg),1); % No ref or anom data for O
                app.pdata.RDA = app.pdata.REF;
                app.pdata.ADA = app.pdata.REF;
                app.pdata.cgod = app.CGOD.Oxygen;
            else
                app.pdata.REF = NaN(sum(tg),1); % No ref or anom data for T,S
                app.pdata.RDA = app.pdata.REF;
                app.pdata.ADA = app.pdata.REF;
            end

            [uC,~,~] = unique(pd(:,app.fI.STA));
            mRDA = nan(size(uC,1), 6); % SDN cycle lon lat mean std
            mADA = mRDA ;

            meta_inds = [app.fI.SDN, app.fI.STA, app.fI.LON, app.fI.LAT]; 
            for ct = 1:size(uC,1)
                tc  = pd(:,app.fI.STA) == uC(ct);
                tmp = pd(tc,:); % f get for meta data

                % SDN Cycle, Lon Lat, mean, std
                % mRDA(ct,1:6) = [tmp(1,meta_inds), ...
                %     mean(app.pdata.RDA(tc),'omitnan'), ...
                %     std(app.pdata.RDA(tc),[],'omitnan')]; 
                mRDA(ct,1:6) = [tmp(1,meta_inds), ...
                    median(app.pdata.RDA(tc),'omitnan'), ...
                    std(app.pdata.RDA(tc),[],'omitnan')]; 

                % mADA(ct,1:6) = [tmp(1,meta_inds), ...
                %     mean(app.pdata.ADA(tc),'omitnan'), ...
                %     std(app.pdata.ADA(tc),[],'omitnan')];
                mADA(ct,1:6) = [tmp(1,meta_inds), ...
                    median(app.pdata.ADA(tc),'omitnan'), ...
                    std(app.pdata.ADA(tc),[],'omitnan')];
            end
            app.pdata.mRDA = mRDA;
            app.pdata.mADA = mADA;

            clear Iflds tf  flds pstate tP tQC tg tC tc uC mRDA mADA ct
            clear meta_inds pd
 
        end
        
        function update_plots(app) 
            %app.TabGroup.Visible ='off';
            d         = app.pdata; % stucture of subsetted param data to plot
            
            tab_title = app.TabGroup.SelectedTab.Title; % RAW or QC
            PlotType  = app.PlotTypeButtonGroup.SelectedObject.Text; %deep,surf,prof
            param     = app.ParameterButtonGroup.SelectedObject.Text;
            ref_str   = app.ReferenceDataButtonGroup.SelectedObject.Text;
            TT        = datetime(d.SDN,'ConvertFrom','datenum');
            x_lim     = [app.CycleMin.Value app.CycleMax.Value];
            tf        = ismember(app.finfo.ftrack(:,2), x_lim);
            x_limTT   = datetime(app.finfo.ftrack(tf,1),'ConvertFrom','datenum');
            
            ax_tab = regexprep(tab_title,' ','');
            arrayfun(@cla,findall(app.(sprintf('%sTab',ax_tab)),'type','axes'));
            arrayfun(@delete,findall(app.(sprintf('%sTab',ax_tab)),'type','colorbar'));
            arrayfun(@delete,findall(app.(sprintf('%sTab',ax_tab)),'type','legend'));

            %Quick fix for rare cases of dead floats that only have a single cycle.
            %When redrawing plots with new x-axis limits, 
            if x_lim(1) == x_lim(2)
                x_lim(2) = x_lim(2)+1;
            end

            %If there is only a single data point, the x_limTT needs to have a dummy date added
            if length(x_limTT) == 1
                x_limTT(2) = x_limTT(1) + days(1); %Add upper bound of 1 extra day
            end

            % MAKE SURE THERE ARE DATA TO PLOT
            if isempty(d.P) % NO GOOD PARAM DATA IN THE SELECTED SUBSET
                alert_cell = {sprintf(['No data to plot for %s in the ',...
                    'selected subset! Try expanding cycle or presure ', ...
                    'ranges.'], param), 'WARNING!'};
                uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
                return
            end
            
            % Raw or Adjusted data
            RorA = 'R';
            if strcmp(tab_title,'QC')
                RorA = 'A';
            end

            % get cycle breakpoints for plotting if they exist
            cgod  = d.cgod;
            nodes = [];
            if ~isempty(cgod) 
                nodes = cgod(:,1);
                % get match up from complete track in case a mismatch
                tf = ismember(app.finfo.ftrack(:,2),nodes);
                if sum(tf) < numel(nodes)
                    tf2 = ismember(nodes, app.finfo.ftrack(tf,2));

                    alert_cell = {sprintf(['No %s data exist at cycle ',...
                        'node %d. Only cycle nodes with valid data will ', ...
                        'be plotted. Please update your adjustments ', ...
                        'table'], param, nodes(~tf2)), 'WARNING!'};

                    uialert(app.UIFigure, alert_cell{1}, alert_cell{2});

                    nodes = nodes(tf2);
                    clear tf2
                end
                nodes_sdn = app.finfo.ftrack(tf,1);
                nodes_TT  = datetime(nodes_sdn,'ConvertFrom','datenum');
            end

            y_labels = {'Nitrate' 'µmol kg^{-1}'; 'pH' ''; ...
                'Oxygen' 'µmol kg^{-1}'; 'Temperature' '°C'; 'Salinity' 'pss'};
            tY       = strcmp(y_labels(:,1), param);

            if ( strcmp(PlotType,'Deep') || strcmp(PlotType,'Surface')) ...
                    && (strcmp(tab_title,'RAW') || strcmp(tab_title,'QC') )

                % ****  top left cycle vs param data  ****
                axh = sprintf('UIAxes_%sCycleData',tab_title);
                axhcb = sprintf('UIAxes_Hidden4CB%s',tab_title);
                colorbar(app.(axhcb),'off');
                colormap(app.(axh), app.cmaps.default)
                YF  = d.([RorA,'D']); % float data
                YR  = d.REF; % reference data
                y_lim = [min([YF;YR]) max([YF;YR])];

                %In rare cases, the upper and lower y_lim values are identical, causing an error.
                %In this rare case, this condition adds a small amount to prevent the error.
                if y_lim(1) == y_lim(2)
                    y_lim(2) = y_lim(2) + .01;
                end               

                app.(axh).Box  = 'On';
                app.(axh).YDir = 'Normal';
                p1 =plot(app.(axh), d.STA, YF, 'ko','MarkerSize',4, ...
                    'MarkerFaceColor', app.colors.blu);
                ylabel(app.(axh), sprintf('%s  %s', y_labels{tY,1}, ...
                    y_labels{tY,2}));
                xlabel(app.(axh), regexprep(regexp(axh,'Cycle|Sdn',...
                    'match','once'),'Sdn','Date'));
                xlim(app.(axh), x_lim );
                hold(app.(axh),'On')
                p2 = plot(app.(axh), d.STA, YR, 'ko','MarkerSize',4, ...
                    'MarkerFaceColor', app.colors.red);

                % add break points             
                if ~isempty(nodes)
                    nodeX = repmat(nodes',2,1);
                    nodeY = repmat(y_lim',1,length(nodes));
                    plot(app.(axh),nodeX, nodeY,'--','Color',app.colors.dor)
                end
                hold(app.(axh),'Off')
                if sum(isnan(y_lim)) == 0
                    %In rare cases, the upper and lower y_lim values are identical, causing an error.
                    %In this rare case, this condition adds a small amount to prevent the error.
                    if y_lim(1) == y_lim(2)
                        y_lim(2) = y_lim(2) + .01;
                    end
                    ylim(app.(axh), y_lim );
                end
                app.(axh).Title.String = '';


                % ****  bottom left cycle vs float - ref anomaly  ****
                axh = sprintf('UIAxes_%sCycleAnom',tab_title);
                YF  = d.([RorA,'DA']); % float - ref anomally
                YFm = d.(['m',RorA,'DA']); % float - ref anomally cycle means
                % SDN, Cycle, Lon Lat, mean, std

                app.(axh).Box  = 'On';
                app.(axh).YDir = 'Normal';
                
                patch_data = [YFm(:,2),YFm(:,5)+YFm(:,6); ...
                    flip([YFm(:,2),YFm(:,5)-YFm(:,6)],1)];
                p1 = fill(app.(axh),patch_data(:,1),patch_data(:,2),'k', ...
                    'FaceAlpha', 0.2,'LineStyle','none');
                ylabel(app.(axh), {sprintf('%s anomally %s', y_labels{tY,1}, ...
                    y_labels{tY,2}), 'Float - Ref'});
                xlabel(app.(axh), regexprep(regexp(axh,'Cycle|Sdn',...
                    'match','once'),'Sdn','Date'));
                xlim(app.(axh), x_lim);
                hold(app.(axh),'On')
                p2 =plot(app.(axh), d.STA, YF, 'ko','MarkerSize',4, ...
                    'MarkerFaceColor', app.colors.ora);
                p3 =plot(app.(axh), YFm(:,2), YFm(:,5), 'k-','Linewidth',2);

                % add break points
                y_lim = [min(YF) max(YF)];

                %In rare cases, the upper and lower y_lim values are identical, causing an error.
                %In this rare case, this condition adds a small amount to prevent the error.
                if y_lim(1) == y_lim(2)
                    y_lim(2) = y_lim(2) + .01;
                end

                if ~isempty(nodes)
                    nodeX = repmat(nodes',2,1);
                    nodeY = repmat(y_lim',1,length(nodes));
                    plot(app.(axh),nodeX, nodeY,'--','Color',app.colors.dor)
                end

                tstr = '';
                if strcmp(param,'Nitrate') || strcmp(param, 'pH')
                    pat = 'mean= %0.1f  median= %0.1f  std= %0.1f';
                    if strcmp(param, 'pH')
                        pat = regexprep(pat,'0.1','0.3');
                    end
                    tstr = sprintf(pat, mean(YFm(:,5),'omitnan'), ...
                        median(YFm(:,5), 'omitnan'), std(YFm(:,5),[],'omitnan'));
                end
                title(app.(axh), tstr);

                hold(app.(axh),'Off')
                %Resets the y limits of the plot
                if sum(isnan(y_lim)) == 0
                    ylim(app.(axh), y_lim);
                end
                % pos2 = app.(axh).InnerPosition;
                % app.(axh).InnerPosition = [pos1(1) pos2(2) pos1(3:4)];


                % ****  top right Date vs param data  ****
                axh = sprintf('UIAxes_%sSdnData',tab_title);
                YF  = d.([RorA,'D']); % float data
                YR  = d.REF; % reference data
                Lstr = {'Float' ref_str};
                

                app.(axh).Box = 'On';
                app.(axh).YDir = 'Normal';
                plot(app.(axh), TT, YF, 'ko','MarkerSize',4, ...
                    'MarkerFaceColor', app.colors.blu);
                % ylabel(app.(axh), sprintf('%s  %s', y_labels{tY,1}, ...
                %     y_labels{tY,2}));
                xlabel(app.(axh), regexprep(regexp(axh,'Cycle|Sdn',...
                    'match','once'),'Sdn','Date'));
                xlim(app.(axh), x_limTT);

                str1 =sprintf('%s{%f %f %f}Float', '\color[rgb]', app.colors.blu);
                if strcmp(param,'Nitrate') || strcmp(param, 'pH') 
                    str2 =sprintf('%s{%f %f %f}Reference', '\color[rgb]', app.colors.red);
                else
                    str2 = '';
                end
                title(app.(axh), [str1,'   ',str2], 'Interpreter','tex')
            
                
                hold(app.(axh),'On')
                plot(app.(axh), TT, YR, 'ko','MarkerSize',4, ...
                    'MarkerFaceColor', app.colors.red);

                % add break points
                y_lim = [min([YF;YR]) max([YF;YR])];

                %In rare cases, the upper and lower y_lim values are identical, causing an error.
                %In this rare case, this condition adds a small amount to prevent the error.
                if y_lim(1) == y_lim(2)
                    y_lim(2) = y_lim(2) + .01;
                end

                if ~isempty(nodes) 
                    nodeX = repmat(nodes_TT',2,1);
                    nodeY = repmat(y_lim',1,length(nodes));

                    plot(app.(axh),nodeX, nodeY,'--','Color',app.colors.dor)
                end
                hold(app.(axh),'Off')
                if sum(isnan(y_lim)) == 0
                    ylim(app.(axh), y_lim );
                end


                % ****  bottom Right Date vs float - ref anomaly  ****
                axh = sprintf('UIAxes_%sSdnAnom',tab_title);
                YF  = d.([RorA,'DA']); % float - ref anomally
                YFm = d.(['m',RorA,'DA']); % float - ref anomally cycle means
                mTT = datetime(YFm(:,1),'ConvertFrom','datenum');
                patchTT = [mTT; flip(mTT)];
                patchY  = [YFm(:,5)+YFm(:,6); flip(YFm(:,5)-YFm(:,6))];
                app.(axh).Box  = 'On';
                app.(axh).YDir = 'Normal';

                p1 = fill(app.(axh),patchTT,patchY ,'k', ...
                    'FaceAlpha', 0.2,'LineStyle','none');
                xlabel(app.(axh), regexprep(regexp(axh,'Cycle|Sdn',...
                    'match','once'),'Sdn','Date'));
                xlim(app.(axh),x_limTT);
                hold(app.(axh),'On')
                p2 =plot(app.(axh), TT, YF, 'ko','MarkerSize',4, ...
                    'MarkerFaceColor', app.colors.ora);
                p3 =plot(app.(axh), mTT, YFm(:,5), 'k-','Linewidth',2);

                % add break points
                y_lim = [min(YF) max(YF)];

                %In rare cases, the upper and lower y_lim values are identical, causing an error.
                %In this rare case, this condition adds a small amount to prevent the error.
                if y_lim(1) == y_lim(2)
                    y_lim(2) = y_lim(2) + .01;
                end

                if ~isempty(nodes)
                    nodeX = repmat(nodes_TT',2,1);
                    nodeY = repmat(y_lim',1,length(nodes));
                    plot(app.(axh),nodeX, nodeY,'--','Color',app.colors.dor)
                end
                hold(app.(axh),'Off')
                if sum(isnan(y_lim)) == 0
                    ylim(app.(axh), y_lim);
                end

                % Add title with BIC & RMSE if QC & DEEP for Natrate & pH
                % only
                app.(axh).Title.String = ''; % default
                if strcmp(PlotType,'Deep') && strcmp(tab_title,'QC') && ...
                        (param(1) == 'N' || param(1) == 'p')
                    tstr = sprintf('BIC= %0.2f   RMSE= % 0.2e', ...
                        app.QC_info.(param).BIC, app.QC_info.(param).RMSE); 
                    app.(axh).Title.String = tstr;
                end
                 

                % pos4 = app.(axh).InnerPosition;
                % app.(axh).InnerPosition = [pos3(1) pos4(2) pos1(3:4)];
                %app.(sprintf('%sTab',ax_tab)) = 'on';

                clear axh RF YF y_lim pos2 
            % *************************************************************
            % PROFILE PLOTS
            elseif strcmp(PlotType,'Profile') ...
                    && (strcmp(tab_title,'RAW') || strcmp(tab_title,'QC') )
                % build matrix for contour plots

                % top left anom or conc contour plots
                axh   = sprintf('UIAxes_%sCycleData',tab_title);
                axhcb = sprintf('UIAxes_Hidden4CB%s',tab_title);

                %colorbar(app.(axh),'off');
                colorbar(app.(axhcb),'off');
                uC   = unique(d.STA);
                F    = d.([RorA,'D']); % float data
                R    = d.REF;
                P    = d.P;
                Y    = (0:5:2000)';
                Ylow = ([0,5,10,25,50:50:1000,1100:100:2000])'; % for avg prof plot
                
                
                if ~isempty(regexp(param,'^Nitrate|^pH','once'))
                    Zanom = d.([RorA,'DA']); % float - ref anomally
                    tcell = {'Float - Ref anomalies' , ...
                        sprintf('%s  %s', y_labels{tY,1}, y_labels{tY,2})};
                    mn = mean(Zanom(:),'omitnan');
                    sd = std(Zanom(:),[],'omitnan');
                    c_range = max([abs(mn-3*sd), abs(mn+3*sd)]);
                    c_lim = [-c_range, c_range];
                    c_ticks = [0 0.5 1];
                    c_ticklabels = round([c_lim(1) 0 c_lim(end)],2);
                    avg_xlab  = sprintf('Avg float - ref, %s',y_labels{tY,2});
                    colormap(app.(axh), app.cmaps.anom);
                    colormap(app.(axhcb), app.cmaps.anom);


                else % just plot the data
                    Zanom = F; % float - ref anomally
                    tcell = {'' , ...
                        sprintf('%s  %s', y_labels{tY,1}, y_labels{tY,2})};
                    c_lim = [min(Zanom(:)), max(Zanom(:))];
                    c_ticks = [0 1];
                    c_ticklabels = round([c_lim(1) c_lim(end)],1);
                    avg_xlab  = sprintf('Avg %s, %s',y_labels{tY,2},y_labels{tY,2});
                    colormap(app.(axh), app.cmaps.default);
                    colormap(app.(axhcb), app.cmaps.default);
                end

                ZA  = NaN(size(Y,1), size(uC,1)); % interp anomally matrix
                ZP  = ZA; % interp parameter matrix
                ZR  = ZA;
                tnan = isnan(Zanom); %for Navis & SOLO with lots of NO3 NaN's
                for ct = 1:size(uC,1)
                    t1 = d.STA == uC(ct);
                    tg = t1 & ~tnan;

                   %if sum(tg) ~= numel(unique(P(tg))), keyboard,end % TESTING
                   % ading a unique P test because ua90254 cycle 106 broke
                   % this. When I look at the data the P values are still 0.1
                   % different (46.8 and 46.79?) ???

                   tmpi     = [P(tg), Zanom(tg),F(tg),R(tg)];
                   [~,ia,~] = unique(tmpi(:,1),'stable');

                    if size(tmpi,1) > 1
                        ZA(:,ct) = interp1(tmpi(ia,1), tmpi(ia,2), Y); % anom
                        ZP(:,ct) = interp1(tmpi(ia,1), tmpi(ia,3), Y); % param
                        ZR(:,ct) = interp1(tmpi(ia,1), tmpi(ia,4), Y); % param
                    else
                        fprintf('sum(tg) = 0 for cycle %d\n',uC(ct)); %TESTING
                    end
                end
                clear tmpi ia

                y_lim = [app.Pmin.Value app.Pmax.Value];
                x_lim = [app.CycleMin.Value app.CycleMax.Value];

                %In rare cases, the upper and lower x_lim and y_lim values are identical, causing an error.
                %In this rare case, this condition adds a small amount to prevent the error.
                if x_lim(1) == x_lim(2)
                    x_lim(2) = x_lim(2) + 1;
                end
                if y_lim(1) == y_lim(2)
                    y_lim(2) = y_lim(2) + .01;
                end

                % can't / don't make contour plot if less than two sample
                % points or profiles or if all the data = NaN
                if ~any(size(ZA) < 2) && sum(~isnan(ZA(:)))
                    C1 = contourf(app.(axh), uC,Y, ZA, 30, 'LineColor', 'none');
                    app.(axh).YDir = 'Reverse';
                    app.(axh).Box = 'on';
                    title(app.(axh), tcell);
                    ylabel(app.(axh),'Pressure');
                    xlabel(app.(axh),'Cycle');
                    app.(axh).YLim = y_lim;
                    app.(axh).XLim = x_lim;
                    app.(axh).CLim = c_lim;

                    cbC1 = colorbar(app.(axhcb)); % assign to hideen axis
                    cbC1.Location = 'North';
                    cbC1.Ticks = c_ticks;
                    cbC1.TickLabels = c_ticklabels;
                    %cbC1.Label.String = 'Cycle #';
                    cbC1.FontSize = 12;
                    % cbC1.Position(4) = 0.02;
                end


                % ****  bottom left cycle avg float - ref anomaly vs P  ****
                axh = sprintf('UIAxes_%sCycleAnom',tab_title);
                mZ  = mean(ZA,2,'omitnan'); %  avg float - ref anomally
                sZ  = std(ZA,[],2,'omitnan'); % std
                tY = ismember(Y,Ylow);

                app.(axh).Box  = 'On';
                app.(axh).YDir = 'Reverse';

                p1 = errorbar(app.(axh), mZ(tY), Y(tY), sZ(tY),'k-','horizontal');
                ylabel(app.(axh), 'Pressure, dbar');
                xlabel(app.(axh), avg_xlab);
                
                if ~any(isnan(c_lim)) % will be NaN if no anom data
                    xlim(app.(axh), c_lim)
                    ylim(app.(axh), y_lim)
                end


                tstr = '';
                if strcmp(param,'Nitrate') || strcmp(param, 'pH')
                    pat = 'mean= %0.1f  median= %0.1f  std= %0.1f';
                    if strcmp(param, 'pH')
                        pat = regexprep(pat,'0.1','0.3');
                    end
                    tstr = sprintf(pat, mean(mZ(tY),'omitnan'), ...
                        median(mZ(tY), 'omitnan'), std(mZ(tY),[],'omitnan'));
                end
                title(app.(axh), tstr);


                % **********************************************************
                % Plot top right: param avg profile with GLODAP
                axh = sprintf('UIAxes_%sSdnData',tab_title);
                app.(axh).Box  = 'On';
                app.(axh).YDir = 'Reverse';
                app.(axh).Title.Text.String = '';
                tX       = strcmp(y_labels(:,1), param);

                mZ  = mean(ZP,2,'omitnan'); %  avg float - ref anomally
                sZ  = std(ZP,[],2,'omitnan'); % std
                mR  = mean(ZR,2,'omitnan'); 
                %tY = ismember(Y,Ylow);
                % Create pach of std
                patch_data = [Y,mZ-sZ; flip([Y,mZ+sZ],1)];
                x_lim3 = [min(mZ-sZ) max(mZ+sZ)];

                p1 = patch(app.(axh), patch_data(:,2), patch_data(:,1), ...
                    app.colors.blu,'EdgeColor','none','FaceAlpha',0.2);
                
                xlabel(app.(axh), sprintf('%s  %s', y_labels{tX,1}, ...
                    y_labels{tX,2}));
                app.(axh).YLim = y_lim;
                if ~any(isnan(x_lim3)) % don't set if anom array all nan
                    app.(axh).XLim = x_lim3;
                end
                hold(app.(axh),'on');

                p2 = plot(app.(axh), mZ, Y,'Color',app.colors.blu);
                p3 = plot(app.(axh), mR, Y,'Color',app.colors.red);

                if ~isempty(app.glodap.data) % try plotting glodap data
                    
                    gflds = fieldnames(app.glodap.I);
                    tf    = ismember(gflds,{'SDN' 'STA' 'P'}); % remove these
                    gflds = gflds(~tf);
                    tf    = ~cellfun(@isempty, regexpi(gflds,['^',param(1)],'once'));
                    fld   = gflds{tf}; % index field for param in glodap matrix
                    tg    = app.glodap.data(:,app.glodap.I.P) >= app.Pmin.Value & ...
                        app.glodap.data(:,app.glodap.I.P) <= app.Pmax.Value & ...
                        ~isnan(app.glodap.data(:,app.glodap.I.(fld)));

                    if any(tg)

                        %Load in relevant GLODAP data
                        gdata = app.glodap.data(tg,:);
                        x_tmp = [min(gdata(:,app.glodap.I.(fld))), ...
                            max(gdata(:,app.glodap.I.(fld)))];
                        x_lim3 = [min([x_lim3,x_tmp]),max([x_lim3,x_tmp])];
                        app.(axh).XLim = x_lim3;

                        %Sort the GLODAP data by date to make things more organized
                        [~,iDateSorted] = sort(gdata(:,2),'ascend');
                        gdata = gdata(iDateSorted,:);
                        
                        %Create a list of date vectors from the GLODAP data for proper labelling of casts
                        dvec  = datevec(gdata(:,app.glodap.I.SDN)); % for year coloring 

                        %[unique data values;unique data starting indicies;group indicies]
                        [~, iDates, dateGroups] = unique(dvec(:,1:2), 'rows');
                        
                        %If there are too many casts, the legend gets way too crowded, so limit this to the most recent 12 casts
                        if length(iDates) > 18
                            disp('Substantial number of GLODAP casts! Limiting to the most recent!')
        
                            %Subset the most recent cast dates (this number of dates fits the map plot)
                            iDates = iDates(end-17:end);
        
                            %Subset the date groups by the new indicies that were just created above
                            finalDateGroup = dateGroups(end);
                            newStartDateGroup = finalDateGroup - 17;
                            newDateGroups = dateGroups>=newStartDateGroup & dateGroups<=finalDateGroup;
                            dateGroups = dateGroups(newDateGroups);
                            
                            %Subset GLODAP data for new dates
                            gdata = gdata(newDateGroups,:);
        
                            %Subset the date vectors for consistency when plotting
                            dvec  = datevec(gdata(:,app.glodap.I.SDN));
                            [~, iDates, dateGroups] = unique(dvec(:,1:2), 'rows');
                        end

                        %Assign each unique profile a combination an ID based on date, cruise, station, lat, and long
                        [~,iCast,castGroup] = unique(gdata(:,[2 3 4 6 7]), 'rows');

                        %Number of unique casts
                        nCasts = length(iCast);
        
                        %Create arrays of the unique months and years for all casts
                        uMONTH = dvec(iDates,2);
                        uYR    = dvec(iDates,1);

                        %Create an empty cell array to store GLODAP datetimes to be used in the plot legend
                        GLODAP_legend_dates = {};
        
                        %Populate the cell array with the unique month/year dates of the casts (prevents overcrowding of the legend).
                        for i = 1:length(iDates)
                            GLODAP_legend_dates = [GLODAP_legend_dates;sprintf('GLODAP %d/%d',uMONTH(i),uYR(i))];
                        end
        
                        %Make a parula colormap based on the number of total casts that crossover the float path
                        cmap  = parula(length(iDates));
                        
                        %Create empty cell arrays to append with info for the plot legend
                        for ct = 1:nCasts
                            %A logical array to determine which rows of the GLODAP data are part of the current cast being plotted
                            tfCastGroup = castGroup == ct;

                            %Plot the cast as a profile
                            p4(dateGroups(iCast(ct))) = plot(app.(axh), gdata(tfCastGroup, app.glodap.I.(fld)),gdata(tfCastGroup, app.glodap.I.P), ...
                                'ko','MarkerSize',4, ...
                                'MarkerFaceColor',cmap(dateGroups(iCast(ct)),:));
                        end
                        
                        %Add all of the GLODAP dates and other plot properties into a single legend
                        L3_cell = ['Float';'Ref';GLODAP_legend_dates];
                        L3 = legend([p2,p3,p4], L3_cell,'Location','EastOutside');
                    else
                        L3_cell = {'Float' 'Ref'};
                        L3 = legend([p2,p3], L3_cell,'Location','EastOutside');
                    end
                else
                    fprintf('No GLODAP data to plot for %s\n', param)
                end

                hold(app.(axh),'off');

                % **********************************************************
                % Plot Lower right: 1st two profiles in cycle range for
                %float, ref & bottle data
                axh = sprintf('UIAxes_%sSdnAnom',tab_title);
                app.(axh).YDir = 'Reverse';
                tX       = strcmp(y_labels(:,1), param);

                t1 = d.STA == uC(1);
                p1(1) = plot(app.(axh), F(t1), P(t1),'-','Color',app.colors.blu);
                xlabel(app.(axh), sprintf('%s  %s', y_labels{tX,1}, ...
                    y_labels{tX,2}));
                L4_cell = {sprintf('flt c%d',uC(1))};
                hold(app.(axh), 'on')
                p1(2)   = plot(app.(axh), R(t1), P(t1),'-','Color',app.colors.red);
                x_lim4  = [min([F(t1);R(t1)]), max([F(t1);R(t1)])];
                L4_cell = [L4_cell, sprintf('ref c%d',uC(1))];


                if size(uC,1) >1
                    t1 = d.STA == uC(2);
                    p1(3) = plot(app.(axh), F(t1), P(t1),'--', ...
                        'Color',app.colors.blu);
                    p1(4) = plot(app.(axh), R(t1), P(t1),'--',...
                        'Color',app.colors.red);
                    x_lim4 = [min([F(t1);R(t1);x_lim4(1)]), ...
                        max([F(t1);R(t1);x_lim4(2)])];
                    L4_cell = [L4_cell, sprintf('flt c%d',uC(2)), ...
                        sprintf('ref c%d',uC(2))];
                end

                % Try and get bottle data to plot
                if isfield(app.bottle,'data') % only there if data exists
                    tf_flags = ~cellfun(@isempty, regexp(app.bottle.hdr,...
                        'FLAG_W$','once'));
                    tf_pres = strcmp(app.bottle.hdr,'CTDPRS');

                    if strcmp(param,'pH')
                        filt_str = 'PH_TOT_INSITU$|PH_TOT_INSITU_ALKDIC$';
                        tf_param = ~cellfun(@isempty, ...
                            regexpi(app.bottle.hdr, filt_str,'once'));
                    elseif strcmp(param,'Nitrate')
                        tf_param = strcmp(app.bottle.hdr,'NITRAT');
                    elseif strcmp(param,'Oxygen')
                        tf_param = strcmp(app.bottle.hdr,'OXYGEN');
                    elseif strcmp(param,'Salinity')
                        tf_param = strcmp(app.bottle.hdr,'CTDSAL');
                    elseif strcmp(param,'Temperature')
                        tf_param = strcmp(app.bottle.hdr,'CTDTMP');
                    end

                    %disp(app.bottle.hdr(~tf_flags & tf_param));
                    X = app.bottle.data(:, ~tf_flags & tf_param);
                    Y = app.bottle.data(:, tf_pres); % CTD PRESSURE
                    if ~isempty(X) && any(~isnan(X(:,1)))
                        p1(5)= plot(app.(axh),X(:,1),Y,'ko', ...
                            'MarkerSize',4,'MarkerFaceColor','y');
                        L4_cell = [L4_cell,'bottle'];
                    end
                    if size(X,2) == 2 && any(~isnan(X(:,2)))
                        p1(6) = plot(app.(axh),X(:,2),Y,'ko', ...
                            'MarkerSize',4,'MarkerFaceColor','m');
                        L4_cell = [L4_cell,'bottle2'];
                    end
                end

                hold(app.(axh), 'off')
                xlim(app.(axh),x_lim4);
                ylim(app.(axh),y_lim);
                L4 = legend(p1, L4_cell,'Location','EastOutside');

                % % make upper right plot width the same as lower right incase
                % % no GLODPAP data. This doesn't work!!
                % app.(sprintf('UIAxes_%sSdnData',tab_title)).Position(3) = ...
                %     app.(axh).Position(3);

                drawnow
 
            elseif strcmp(tab_title,'pH pump offset')
                if app.finfo.tf_pH
                    update_pHOffsetPlots(app)
                end


            end
            QCTabSizeChanged(app)
        end


        function update_pHOffsetPlots(app)

            arrayfun(@cla,findall(app.pHpumpoffsetTab,'type','axes'));
            arrayfun(@delete,findall(app.pHpumpoffsetTab,'type','colorbar'));
            arrayfun(@delete,findall(app.pHpumpoffsetTab,'type','legend'));
            
            %OrigSz = app.OrigSize.PO; % use to revert autosizing
            % ****  pH pump offsets for each cycle  ****
            axh    = 'UIAxes_CyclePO';
            POdata = app.pdata.PO_PO;
            Y1 = POdata(:,3); % iterative 3rd order polynomial
            Y2 = POdata(:,7); % linear 2x avg
            X  = POdata(:,1); % Cycle
            y_lim = [min([Y1;Y2;0]) max([Y1;Y2;0])];
            x_lim = [min(X), max(X)];

            %In rare cases, the upper and lower x_lim and y_lim values are identical, causing an error.
            %In this rare case, this condition adds a small amount to prevent the error.
            if x_lim(1) == x_lim(2)
                x_lim(2) = x_lim(2) + 1;
            end
            if y_lim(1) == y_lim(2)
                y_lim(2) = y_lim(2) + .01;
            end

                p1 =plot(app.(axh), X, Y1, 'o','MarkerSize',4, ...
                    'MarkerEdgeColor', app.colors.blu);
                xlim(app.(axh),x_lim);
                hold(app.(axh),'On')
                p2 = plot(app.(axh), X, Y2, '*','MarkerSize',4, ...
                    'MarkerEdgeColor', app.colors.red);
                p3 = plot(app.(axh), x_lim, x_lim*0,'k--');
                hold(app.(axh),'Off')
                if sum(isnan(y_lim)) == 0
                    ylim(app.(axh), y_lim );
                end
                %pos1 = app.(axh).InnerPosition;
                L1 = legend([p1,p2],{'poly interp' 'linear avg'},...
                    'Location','North outside');
                L1.Orientation = 'Horizontal';
                %app.(axh).InnerPosition = pos1;
                % app.(axh).InnerPosition = cell2mat(OrigSz(1));
  
                % ******************************************************
                % plot raw & pump offset corrected pH profiles
                corr_type = app.pHCpOffsetCorrectionButtonGroup.SelectedObject.Text;
                if strcmp(corr_type,'none')
                    tf_col = 4;
                else % get appropriate offset correction
                    tf_col = contains(app.pH_PO.hdr, corr_type(1:3)); % first 3 charcters will match
                end
                %disp(app.pH_PO.hdr)


                %pos3 = app.UIAxes_ProfPOCor.Position;
                PH = app.pdata.PO_pH; % n x 6, sta, P raw, poly lin mixed
                uC = unique(PH(:,1));
                % x_lim3 = [min([PH(:,3);PH(:,4);PH(:,5)],[],'omitnan'), ...
                %           max([PH(:,3);PH(:,4);PH(:,5)],[],'omitnan')];

                x_lim3 = [min([PH(:,3);PH(:,tf_col)],[],'omitnan'), ...
                          max([PH(:,3);PH(:,tf_col)],[],'omitnan')];

                % Build color matrix
                cmap      = colormap(app.(axh));
                cycle_idx = linspace(uC(1), uC(end), size(cmap,1))';
                cmpi      = interp1(cycle_idx, cmap, uC); % cycle colors

                % Not certain why I needed the cla for the profile plots
                % but old data wouldn't clear even with hold = off???
                cla(app.UIAxes_ProfNoCor)
                cla(app.UIAxes_ProfPOCor)
                hold(app.UIAxes_ProfNoCor,'on')
                hold(app.UIAxes_ProfPOCor,'on')

                for ct = 1:size(uC,1)
                    t1 = PH(:,1) == uC(ct);
                    plot(app.UIAxes_ProfNoCor, PH(t1,3), PH(t1,2), ...
                        'Color', cmpi(ct,:));
                    plot(app.UIAxes_ProfPOCor, PH(t1,tf_col), PH(t1,2), ...
                        'Color', cmpi(ct,:));
                end
                
                hold(app.UIAxes_ProfNoCor,'off')
                hold(app.UIAxes_ProfPOCor,'off')
                % xlims = sort([app.UIAxes_ProfNoCor.XLim, ...
                %     app.UIAxes_ProfPOCor.XLim]);
                % app.UIAxes_ProfNoCor.XLim = xlims([1,end]);
                % app.UIAxes_ProfPOCor.XLim = xlims([1,end]);
                app.UIAxes_ProfNoCor.XLim = x_lim3;
                app.UIAxes_ProfPOCor.XLim = x_lim3;

                %colorbar makes axes different size & I can't seem to fix
                %no matter what I try. Create dummy axis to assoc w/ cb
                
                cb3 = colorbar(app.UIAxes_Hidden4CB);
                cb3.Location = 'North';
                cb3.Ticks = [0 1];
                cb3.TickLabels = [uC(1) uC(end)];
                cb3.Label.String = 'Cycle #';
                %cb3.Label.Position(2) = cb3.Label.Position(2)
                cb3.FontSize = 12;
                cb3.Position(4) = 0.02;
                % app.UIAxes_ProfNoCor.InnerPosition = cell2mat(OrigSz(2));
                % app.UIAxes_ProfPOCor.InnerPosition = cell2mat(OrigSz(3));
                % app.UIAxes_ProfPOCor.InnerPosition(3:4) = app.UIAxes_ProfNoCor.InnerPosition(3:4);
                drawnow

        end


        function apply_QC_correction(app)
            %   This function applies the quality control adjustments in
            %   the table to the raw pH or nitrate data to generate
            %   adjusted data. The adjusted data column in the float data
            %   matrix (app.fdata) is updated

            param = app.ParameterButtonGroup.SelectedObject.Text;

            tg    = strcmp(param,'Nitrate') | strcmp(param, 'pH');
            if ~tg
                fprint(['QC corrections can only be applied to Nitrate or',...
                    'pH at this time and not to %s\n'],param);
                return
            end

            % ALL GOOD - CONTINUE
            cgod   = app.CGOD.(param); % parameter specific corrections
            r_cgod = size(cgod,1); % parameter correction nodes count
            CORR   = NaN(size(app.fdata(:,1)));
            % fI index example: N & NA for NO3 raw & NO3 Adjusted,
            % PHO is pH pump offset corrected raw pH
            % fI.SDN, fI.STA, fI.LON, fI.LAT
            % fI.P, fI.S, fI.T
            % fI.O, fI.OA, fI.N, fI.NA
            % fI.PH, fI.PHA, fI.PHO

            % IF QC LEG DOES NOT START ON PROFILE #1 SET DATA = NAN
            if cgod(1,1) > min(app.fdata(:,app.fI.STA)) % 1st QC step does not start at profile 1
                fprintf(['WARNING: QC matrix does not start on the floats starting ', ...
                    'cycle!\nCycles prior to QC matrix start will be extrapolate QC back in time!',...
                    'Please re-check QC adjustments!\n']);
            end

            % *************************************************************************
            % ****************************    pH QC   *********************************
            % pH ALSO HAS A PUMP OFFSET TERM But calculation taken care of in
            % get_sageV2_float_data.m
            % NO GAIN CORRECTION FOR PH
            %
            % PH should really be corrected in k0 space, but this requires a little
            % more effort. k0 needs to be backed out of the "true pH" (raw pH +
            % correction) and then pH needs to be recalculated with the updated k0 for
            % every profile. The pH QC matrix would have k0 offsets and drifts instead
            % of pH. When in pH space a simple  Nernstian response is assumed to adjust
            % the pH from the correction depth temperature to the sample temperature.
            % If you assume a a max T range of [-1,ref 30, surf], this ammounts to a
            % 10% decrease in the pH correction for the warm surface waters correction
            % if the offset were -0.03 the surface offset would be -0.027 or a 3 milli
            % pH change
            % *************************************************************************
            if strcmp(param, 'pH')
                % pH_offset_method = app.QC_info.(param).pH_offset; % 'poly','linear','mix','none', ''
                % 
                % if isempty(pH_offset_method) || strcmp(pH_offset_method,'none')
                %     IND = app.fI.PH;
                % else
                %     IND = app.fI.PHO; % raw but pH pump offset corrected using method: pH_offset_method
                % end
                IND = app.fI.PH;

                % ********************************************************************
                % Get QC correction P range to get avg T at QC depth for each cycle to
                % be used in TCOR calculation.
                PlotType = app.PlotTypeButtonGroup.SelectedObject.Text; %deep,surf,prof

                % P range set for QC correction
                PresRange = [app.state.(param).(PlotType)(1), ...
                    app.state.(param).(PlotType)(2)];


                uC        = unique(app.fdata(:,app.fI.STA));
                tP        = app.fdata(:,app.fI.P) >= PresRange(1) & ...
                            app.fdata(:,app.fI.P) <= PresRange(2);
                QC_REF_T  = NaN(size(uC)); % predim
                TCOR      = CORR; % predim

                tmp1 = app.fdata(tP,:); % subsetted to QC press range
                for ct = 1:size(uC,1)
                    t1 = tmp1(:,app.fI.STA) == uC(ct);
                    if any(t1)
                        % avg assumes sample dP is constant with in P range (equal weight)
                        QC_REF_T(ct) = mean(tmp1(t1, app.fI.T),1,'omitnan');
                    end
                end

                % Any missing cycles? If so interp / extrap with nearest neighbor
                % could also go back to default fill = 2 deg C
                tnan = isnan(QC_REF_T);
                if any(tnan) % filling missing values with nearst neigbor match
                    fprintf(['Ref T for pH Tcor is missing cycles! These will be ',...
                        'filled using nearest neighbor interpolation / extrapolation\n']);
                    QC_REF_T(tnan) = interp1(uC(~tnan), QC_REF_T(~tnan), uC(tnan), ...
                        'nearest','extrap');
                end


                [~,Locb]      = ismember(app.fdata(:,app.fI.STA), uC); % full data vs cycle avgerages
                QC_REF_T_full = QC_REF_T(Locb); % populate ref temps for all cycles & samples
                TCOR          = (QC_REF_T_full + 273.15) ./ ...
                                (app.fdata(:,app.fI.T) + 273.15); % Ratio in Kelvin

                % OK NOW CALCULATE pH CORRECTION - step through node blocks
                for ct = 1:r_cgod
                    if ct < r_cgod
                        t1 = app.fdata(:,app.fI.STA) >= cgod(ct,1) &  ... % get block
                            app.fdata(:,app.fI.STA) < cgod(ct+1,1);
                    else
                        t1 = app.fdata(:,app.fI.STA) >= cgod(ct,1);
                    end

                    elapsed_yrs = (app.fdata(t1, app.fI.SDN) - ...
                        min(app.fdata(t1, app.fI.SDN),[],1,'omitnan')) ./ 365;
                    CORR(t1)  = elapsed_yrs .* cgod(ct,4) + cgod(ct,3); % etime*drift + offset
                end
                app.fdata(:, app.fI.PHA) = app.fdata(:,IND) - (CORR .* TCOR); % update adjusted pH
            end

            % ************************************************************
            % ******************************  NITRATE  *******************
            % CYCLE, GAIN, OFFSET, DRIFT
            % ************************************************************
            if strcmp(param, 'Nitrate')
                for ct = 1:r_cgod
                    if ct < r_cgod
                        t1 = app.fdata(:, app.fI.STA) >= cgod(ct,1) &  ... % get block
                            app.fdata(:,app.fI.STA) < cgod(ct+1,1);
                    else
                        t1 = app.fdata(:,app.fI.STA) >= cgod(ct,1);
                    end

                    elapsed_yrs = (app.fdata(t1, app.fI.SDN) - ...
                        min(app.fdata(t1, app.fI.SDN),[],1,'omitnan')) ./ 365;
                    CORR(t1) = elapsed_yrs .* cgod(ct,4) + cgod(ct,3); % etime*drift + offset
                end
                %only 1 gain value for Nitrate so can pull from any row
                app.fdata(:,app.fI.NA) = (app.fdata(:,app.fI.N) - CORR) ./ cgod(ct,2); % update adjusted NO3
                %disp('adj NO3 has been updated!')
            end

        end
        
        function update_QC_info(app)
            % This function gets called every time changes are accepted by
            % the GUI. This information will get propagated to the QC list
            % file
            % This function should be called after the anomaly and 
            % plot data have been updated

            param = app.ParameterButtonGroup.SelectedObject.Text;

            tg    = strcmp(param,'Nitrate') | strcmp(param, 'pH');
            if ~tg
                fprint(['QC info can only be updated for Nitrate or',...
                    'pH at this time and not for %s\n'],param);
                return
            end

            cgod     = app.CGOD.(param); % parameter specific corrections
            r_cgod   = size(cgod,1); % parameter correction nodes count
            ref_str  = app.ReferenceDataButtonGroup.SelectedObject.Text;
            errorLim = app.error_limits.(param); % pH or Nitrate

            % GET BIC & SET QC info
            %resids = app.pdata.ADA; % adjusted data anomaly
            resids = app.pdata.mADA(:,5); % median adjusted data anomaly

            tnan = isnan(resids);
            if any(tnan)
                fprintf(['WARNING: %d NaNs detected in anomaly array ', ...
                    '(app.pdata.mADA). They will be removed prior to BIC',...
                    'estimation in update_QC_info\n'], sum(tnan));
                resids = resids(~tnan);
            end


            n      = length(resids);
            SSR    = sum(resids.^2);
            K      = r_cgod + sum(cgod(:,4) ~= 0); % intercepts + non zero slopes
            bic    = log(SSR./n + errorLim.^2) + K*log(n) / n;
            
            app.QC_info.(param).Date      = datestr(now,'mm/dd/yy HH:MM');
            app.QC_info.(param).Ref       = ref_str;
            app.QC_info.(param).PresRange = app.state.(param).Deep;

            app.QC_info.(param).NodeCt    = r_cgod; % save for reporting in QC log
            app.QC_info.(param).BIC       = bic;
            app.QC_info.(param).RMSE      = sqrt(SSR./n);
            
            if strcmp(param, 'pH')
                 app.QC_info.pH_offset = app.CGOD.pH_offset;
            end

           % remove all styles from table once adjusted data have been
           % updated with the new adjustments
           removeStyle(app.QCATable) 
           app.ApplyCelleditsButton.Enable = 'off';
           app.ApplyCelleditsButton.BackgroundColor = [0.96,0.96,0.96];
           app.CalculateAdjustmentsButton.BackgroundColor = [0.96,0.96,0.96];

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set dirs structure & plotting state structures
            % The state stuctures hold plotting & subsetting arrays for
            % each parameter (i.ez: state.NO3, state.PH, state.O2, ...

            % TESTING
            % jp = app.UIFigure.Position;
            % disp(jp)
            % app.UIFigure.Units = 'normalized';
            % jp = app.UIFigure.Position;
            % disp(jp)

            % SET SAGEV2 VERSION NUMBER HERE
            % At the highest level of the app hierarchy there is a 
            % "SHARING DETAILS" section where the app version can go.
            % Unfortunately there appears to be no way tp access this info
            % programatically!
            app.info.version = '2.0'; % A string

            d = sageV2_init(); % see this adjustable stand alone function for guts
            app.dirs          = d.dirs;
            app.mlist         = d.mlist; % structure (with fields: hdr list)
            app.bottle.lookup = d.bottle_lookup;
            app.error_limits  = d.error_limits;
            app.sig_level     = d.sig_level;

            % cell edit box default values get defined in SAGEv2_init.m,
            % but will potentialy get updated in the app function init_states
            app.defaults = d.defaults; % cell edit box default values

            % IF NOT MBARI, disable ODV filereprocess button
            if isempty(d.mlist) 
                app.UPDATEMBARIODVButton.Enable = 'off';
                app.UPDATEMBARIODVButton.Visible = 'off';
            end

            % Set inital range values for edit boxes
            % app.CycleMin.Value = d.default.CycleMin; % do in init_states in case of float reload    
            % app.CycleMax.Value = d.default.CycleMax;
            % app.Pmin.Value     = d.default.Pmin;
            % app.Pmax.Value     = d.default.Pmax;
            % app.XOver_km.Value = d.default.XOver_km;
            % app.QCATable.Data  = d.default.TableData;

            clear d

            % set colormaps
            app.cmaps.default = colormap(app.UIAxes_RAWCycleData); % can be any ax
            load(fullfile(app.dirs.sage,'AnomCmap.mat'),'cmap');
            app.cmaps.anom    = cmap;
            clear cmap

            % Set color palette
            app.colors.grn = [0 204 0]/256;       % green
            app.colors.ora = [255 178 102]/256;   % light orange
            app.colors.yel = [255 255 51]/256;    % yellow
            app.colors.red = [204 0 0]/256;       % drk red
            app.colors.blu = [0 102 204]/256;     % blue
            app.colors.err = [255 153 153]/256;   % light red: error in entry color
            app.colors.gry = [128 128 128]/256;   % grey
            app.colors.ltgry = [224 224 224]/256; % grey
            app.colors.tur = [0 204 204]/256;     % turquise
            app.colors.dor = [255 128 0]/256;     % dark orange

            % Define ui styles for table modifications
            app.uistyles.s1 = uistyle('BackgroundColor','yellow');

            % Get original InnerPosition axes sizes so I can resize back to
            % original. Stored as nx1 cell array of 1x4 matrices
            app.OrigSize.Raw = get(findobj(app.RAWTab,'Type','axes'),'InnerPosition');

            app.OrigSize.QC  = get(findobj(app.RAWTab,'Type','axes'),'InnerPosition');
            app.OrigSize.PO  = get(findobj(app.pHpumpoffsetTab,'Type','axes'),'InnerPosition');


            % CHECK ischange FUNCTION EXISTANCE & DISABLE FIND CHANGEPOINT BUTTON IF
            % IT DOESN'T EXIST (OLDER MATLAB VERSIONS)
            if exist('ischange', 'file') ~= 2
                alert_cell = {['Your version of MATLAB does not support',...
                    ' "ischange.m"'] 'Define change-points manually'};
                uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
                set(app.FindChangePointsButton, 'enable', 'off') % turn off panel
            end

            % Set default QC history string if a float hasn't been loaded
            % yet
            app.QC_history = {'No float has been loaded into sageV2 yet!'};


            % SET AXES PROPERTIES - PUMP OFFSET TAB 
            % app.UIAxes_CyclePO.Units = 'normalized';
            % app.UIAxes_CyclePO.PositionConstraint = 'innerposition';
            % 
            % app.UIAxes_ProfNoCor.Units = 'normalized';
            % app.UIAxes_ProfNoCor.PositionConstraint = 'innerposition';
            % 
            % app.UIAxes_ProfPOCor.Units = 'normalized';
            % app.UIAxes_ProfPOCor.PositionConstraint = 'innerposition';
            % 
            % app.UIAxes_Hidden4CB.Units = 'normalized';
            % app.UIAxes_Hidden4CB.PositionConstraint = 'innerposition';


        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)


            % ***********************************************************
            % LOAD FLOAT DATA
            % ***********************************************************
            [fn,pn] = uigetfile({'*.TXT;*.nc'},'SELECT FILE', ...
                app.dirs.float);
            
            % force focus to GUI after uigetfile call which puts the GUI
            % in the background for some reason?
            figure(app.UIFigure);

            if isequal(fn, 0) % operation canceled do nothing
                return
            end     

            % ***********************************************************
            % CLEAN UP FROM PREVIOUS FLOAT IF NECESSARY BUT DO AFTER FILE
            % TRUELY SELECTED
            % ***********************************************************
            % clear axes and dependants
            arrayfun(@cla,findall(app.RAWTab,'type','axes'))
            arrayfun(@cla,findall(app.QCTab,'type','axes'))
            arrayfun(@cla,findall(app.pHpumpoffsetTab,'type','axes'))

            % Clean up any applied table edit related styles / colors
            removeStyle(app.QCATable)
            app.ApplyCelleditsButton.Enable = 'off';
            app.ApplyCelleditsButton.BackgroundColor = [0.96,0.96,0.96];
            app.CalculateAdjustmentsButton.BackgroundColor = [0.96,0.96,0.96];

            app.tf_OK = 0;

            % build file path, set GUI bling
            fn       = regexprep(fn,'CFG','TXT'); % incase CFG file picked acidentally (MBARI)
            float_fp = fullfile(pn,fn);
            app.SelectfileButton.Text = 'Loading float data...';
            % Guarantee app.colors is a struct with an 'ora' field
            if ~isstruct(app.colors) || ~isfield(app.colors, 'ora')
                app.colors = struct();
                app.colors.ora = [1.0, 0.5, 0.0]; % Default Orange RGB
            end

            app.SelectfileButton.BackgroundColor = app.colors.ora;
            fprintf('Loading float data: %s\n',float_fp)
            drawnow % force refresh

            % Try and get float data
            tmp = get_sageV2_float_data(float_fp); % load float data
            if ~isempty(tmp.data)
                app.fhdr  = tmp.hdr;
                app.fdata = tmp.data; % profile data
                app.fdata_orig = app.fdata; % for reloading orig param data
                app.finfo = tmp.info; % meta info
                app.fI    = tmp.info.I; % data column indices
                app.pH_PO = tmp.pH_PO; % pH pump offset data structure
                clear tmp

                if app.finfo.tf_pH == 0 && app.finfo.tf_Nitrate == 0
                    str = sprintf(['WARNING:\nNO QC possible for %s\n', ...
                        'No usable pH or NO3 data!\n',...
                        'All NO3 & pH data are flagged bad\n', ...
                        'or no NO3 or pH sensors aboard float!\n', ...
                        '\nPlease load a different float\n'],...
                        app.finfo.wmo);
                    text(app.UIAxes_RAWCycleData, 0.05, 0.5, str, ...
                        'Color', 'Red','FontSize', 16);
                    app.SelectfileButton.Text = 'No usable data! Load another float';
                    app.SelectfileButton.BackgroundColor = app.colors.err;
                    return
                end

            else
                fprintf('float data failed to load: %s\n',float_fp)
                app.SelectfileButton.BackgroundColor = app.colors.red;
                app.SelectfileButton.Text = 'Float failed to load.';
                return
            end

            % CHECK O2 SENSOR/DATA STATUS AND ISSUE WARNING IF NON-EXISTANT
            % Raw only? no sensor? bad?
            if app.finfo.tf_Oxygen == 0
                if ~isempty(app.fI.OA) % O2 exists! bad or no QC yet?
                    tQFraw = app.fdata(:,app.fI.O+1) ~= app.finfo.QFbad; % raw O2 QF's
                    tQFmiss= app.fdata(tQFraw,app.fI.OA+1) ~= app.finfo.QFmiss; % adj missingQF's for non bad raw

                    if sum(tQFmiss) == 0 % no QC yet
                        alert_cell = {['It appers that oxygen data exist ',...
                            'but have not been QCed yet! This will limit ',...
                            'the QC reference options to WOA & ESPER NO_O2'], ...
                            'WARNING!'};
                        uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
                    else
                        alert_cell = {['It appers that oxygen data exist ',...
                            'but are all bad! This will limit ',...
                            'the QC reference options to WOA & ESPER NO_O2'], ...
                            'WARNING!'};
                        uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
                    end
                else
                    alert_cell = {['No oxygen data exist for this ',...
                        'float.  This will limit the QC reference options ', ...
                        'to WOA & ESPER NO_O2'], 'WARNING!'};
                    uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
                end
            end
            
            % ***********************************************************
            % LOAD REFERENCE DATA
            % ***********************************************************
            I   = app.finfo.I; % get predefined fdata col idx struct
            idx = [I.SDN,I.STA,I.LON,I.LAT,I.P,I.SA,I.T,I.OA];
            app.SelectfileButton.BackgroundColor = app.colors.yel;
            app.SelectfileButton.Text = 'Loading ref data...';
            drawnow

            fprintf('Loading reference data.....\n')
            data_in = app.fdata(:,idx);
            % First, replace DMQC salinity with raw salinity -- this will
            % replace NaN values in adjusted fields with raw values.  We
            % will then still screen the raw data for bad values that MBARI
            % deemed bad, but at least this will replace non-agregious
            % 'bad' (in core-world eyes) with raw data that is still usable
            % on the bgc end...  TM 5/15/25
            tbad_SA    = app.fdata(:,I.SA+1) == app.finfo.QFbad | ... % bad S
                app.fdata(:,I.SA+1) == app.finfo.QFmiss;
            data_in(tbad_SA,6) = app.fdata(tbad_SA,I.S); %replace bad SA with raw S


            % SET BAD OR MISSING DATA FLAGS FOR REF CALCS
            % may need to deal with missing NO3, pH sensor cases
            tbad_S    = app.fdata(:,I.S+1) == app.finfo.QFbad | ... % bad S
                app.fdata(:,I.S+1) == app.finfo.QFmiss;

            tbad_O2    = app.fdata(:,I.OA+1) == app.finfo.QFbad | ... % bad O2
                app.fdata(:,I.OA+1) == app.finfo.QFmiss; 

            tbad_N    = app.fdata(:,I.N+1) == app.finfo.QFbad | ... % bad N
                app.fdata(:,I.N+1) == app.finfo.QFmiss; 

            tbad_PH    = app.fdata(:,I.PH+1) == app.finfo.QFbad | ... % bad PH
                app.fdata(:,I.PH+1) == app.finfo.QFmiss; 

            tfQC = ~[tbad_S, tbad_O2, tbad_N, tbad_PH];

            app.ref_data = get_sageV2_ref_data(data_in, tfQC, 'all', ...
                app.dirs.WOA, app);

            if isempty(app.ref_data)
                fprintf('reference data failed to load!\n')
                app.SelectfileButton.Text = 'Ref data failed to load!';
                app.SelectfileButton.BackgroundColor = app.colors.red;
                alert_cell = {['Reference data could not be loaded! ',...
                    'sageV2 can not be used. Please check reference ',...
                    'data source paths.'], 'WARNING!'};
                uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
                drawnow
                return
            end

            % ***********************************************************
            % GET QC HISTORY FOR REFERENCE
            % ***********************************************************
            % TRY AND GET ANY AVIALABLE QC HISTORY
            log_fp1 = fullfile(app.dirs.QClists, app.dirs.QClog_fn); % Prefered location
            % Safely handle app.dirs.cal if unassigned or missing
            if isprop(app.dirs, 'cal') && ~isempty(app.dirs.cal)
                cal_dir = app.dirs.cal;
            elseif isfield(app.dirs, 'QClog')
                cal_dir = app.dirs.QClog;
            else
                cal_dir = tempdir; % Fallback temporary directory to ignore missing CAL folder
            end

            if isprop(app.dirs, 'QClog_fn') && ~isempty(app.dirs.QClog_fn)
                qc_filename = app.dirs.QClog_fn;
            else
                qc_filename = 'FloatQCList_log.txt';
            end

            %log_fp2 = fullfile(cal_dir, qc_filename);     % MBARI location
            %bad_sensor_fp = fullfile(app.dirs.cal, app.dirs.BadSensor_fn); % MBARI only
            % Safely handle BadSensor_fn reference when MBARI CAL files are absent
            if isfield(app.dirs, 'BadSensor_fn') && ~isempty(app.dirs.BadSensor_fn)
                bad_sensor_fn = app.dirs.BadSensor_fn;
            else
                bad_sensor_fn = 'BadSensorList.txt';
            end

            if isfield(app.dirs, 'cal') && ~isempty(app.dirs.cal)
                cal_dir = app.dirs.cal;
            elseif isfield(app.dirs, 'QClog')
                cal_dir = app.dirs.QClog;
            else
                cal_dir = tempdir;
            end

            bad_sensor_fp = fullfile(cal_dir, bad_sensor_fn);

            % If the file doesn't exist locally, ignore it gracefully
            if ~isfile(bad_sensor_fp)
                % Initialize an empty placeholder table or struct if downstream code expects one
                bad_sensors = []; 
            end
            % Safely handle BadSample_fn reference when MBARI CAL files are absent
            if isfield(app.dirs, 'BadSample_fn') && ~isempty(app.dirs.BadSample_fn)
                bad_sample_fn = app.dirs.BadSample_fn;
            else
                bad_sample_fn = 'BadSampleList.txt';
            end

            if isfield(app.dirs, 'cal') && ~isempty(app.dirs.cal)
                cal_dir = app.dirs.cal;
            elseif isfield(app.dirs, 'QClog')
                cal_dir = app.dirs.QClog;
            else
                cal_dir = tempdir;
            end

            bad_sample_fp = fullfile(cal_dir, bad_sample_fn);

            % If the file doesn't exist locally, set a local placeholder
            if ~isfile(bad_sample_fp)
                bad_samples = []; % Use local variable rather than app.bad_samples
            end % MBARI only
            tf_log = 0;
            qc_history = {sprintf('No available QC history ', ...
                'for %s',app.finfo.wmo)};
            if isfile(log_fp1)
                tf_log = 1;
                log_fp = log_fp1;
            elseif isfile(log_fp2)
                tf_log = 1;
                log_fp = log_fp2;
            else
                qc_history =[qc_history; ...
                    sprintf('QC list log file was not found\n')];
            end
            % If log file exists get it
            if tf_log
                tmp  = readcell(log_fp,'Delimiter','\n');
                tWMO = ~cellfun('isempty', regexp(tmp,app.finfo.wmo,'once'));
                if sum(tWMO) > 0
                    qc_history = tmp(tWMO);
                    %If bad sample or sensor lists exist, add them (MBARI)
                    if isfile(bad_sensor_fp)
                        tmp  = readcell(bad_sensor_fp,'Delimiter','\n');
                        tg = ~cellfun('isempty', regexp(tmp,app.finfo.wmo,'once'));
                        if sum(tg)>0
                            qc_history = [qc_history; ' ';...
                                '**** BAD SENSOR LIST LINES ***';
                                tmp(tg)];
                        end
                    end
                    if isfile(bad_sample_fp)
                        tmp  = readcell(bad_sample_fp,'Delimiter','\n');
                        tg = ~cellfun('isempty', regexp(tmp,app.finfo.wmo,'once'));
                        if sum(tg)>0
                            qc_history = [qc_history; ' ';...
                                '**** BAD SAMPLE LIST LINES ***';
                                tmp(tg)];
                        end
                    end
                end
            end
            app.QC_history = qc_history;
            clear log_fp1 log_fp2 log_fp bad_sensor_fp bad_sample_fp
            clear tf_log tmp tWMO tg qc_history


            % calculate anomalies, initialize & plot
            % GET QC ADJUSTMENT FILE (Sprof vs MBARI ??)
            init_states(app) % init & use float data to update plotting states

            update_glodap(app)
            update_anom(app) % generate float - ref anomalies
            update_plot_data(app)
            update_plots(app) % update plots

            app.SelectfileButton.BackgroundColor = app.colors.grn;
            if isfield(app.finfo,'MBARI_ID')
                app.SelectfileButton.Text = sprintf('%s (%s)',...
                    app.finfo.fn, app.finfo.MBARI_ID);
            else
                app.SelectfileButton.Text = app.finfo.fn;
            end
            drawnow

            fprintf('Float %s succesfully loaded into sage!\n', ...
                app.finfo.wmo);

            app.tf_OK = 1;


        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            PlotType = app.PlotTypeButtonGroup.SelectedObject.Text; %deep,surf,prof
            % Update edit box pressure range values
            param  = app.ParameterButtonGroup.SelectedObject.Text;
            MinMax = app.state.(param).(PlotType); % value from state struct
            app.Pmin.Value = MinMax(1);
            app.Pmax.Value = MinMax(2);

            % only allow changes to occur for deep
            RB = findobj(app.DataAdjustmentsPanel,'Type','uibutton');
            if strcmp(PlotType, 'Deep')
                set(RB,'Enable','on')
            else
                set(RB,'Enable','off')
            end
            clear RB MinMax param Plottype
            
            update_plot_data(app)
            update_plots(app) % update plots
            QCTabSizeChanged(app) % apply size changes
            
            
        end

        % Value changed function: CycleMin
        function CycleMinValueChanged(app, event)

            % update all state structures
            params = {app.ParameterButtonGroup.Children.Text};
            cmin   = app.CycleMin.Value;
            if cmin >= app.CycleMax.Value
                app.CycleMin.BackgroundColor = app.colors.err;
                pause(0.5) % set error color & pause breifly
                app.CycleMin.BackgroundColor = 'w';
                % reset to orginal - can use any state for this
                app.CycleMin.Value = app.state.(params{1}).cycles(1);
            else % step through & update all param states
                for ct = 1:length(params) 
                    app.state.(params{ct}).cycles(1) = cmin;
                end
            end
            clear params ct cmin
            update_plot_data(app)
            update_plots(app) % update plots


        end

        % Value changed function: CycleMax
        function CycleMaxValueChanged(app, event)

            % update all state structures
            params = {app.ParameterButtonGroup.Children.Text};
            cmax   = app.CycleMax.Value;
            if cmax <= app.CycleMin.Value
                app.CycleMax.BackgroundColor = app.colors.err;
                pause(0.5) % set error color & pause breifly
                app.CycleMax.BackgroundColor = 'w';
                % reset to orginal - can use any state for this - no change
                app.CycleMax.Value = app.state.(params{1}).cycles(2);
            else
                if cmax > app.finfo.CycleRange(2) % can only go to max cycles
                    fprintf('Max cycles %d\n',app.finfo.CycleRange(2))
                    app.CycleMax.Value = app.finfo.CycleRange(2);
                    cmax = app.finfo.CycleRange(2);
                end
                for ct = 1:length(params) % step through params
                    app.state.(params{ct}).cycles(2) = cmax;
                end
            end


            clear params ct cmax
            update_plot_data(app)
            update_plots(app) % update plots


        end

        % Value changed function: Pmin
        function PminValueChanged(app, event)
            pmin     = app.Pmin.Value;
            param    = app.ParameterButtonGroup.SelectedObject.Text;
            PlotType = app.PlotTypeButtonGroup.SelectedObject.Text; %deep,surf,prof
            if pmin >= app.Pmax.Value
                app.Pmin.BackgroundColor = app.colors.err;
                pause(0.5) % set error color & pause breifly
                app.Pmin.BackgroundColor = 'w';
                % reset to orginal - use param & plottype
                app.Pmin.Value = app.state.(param).(PlotType)(1);
            else % update Pmin volue for given param & plot type
                %fieldnames(app.state)
                app.state.(param).(PlotType)(1) = pmin;
            end
            clear params PlotTypect pmin

            update_plot_data(app)
            update_plots(app) % update plots


        end

        % Value changed function: Pmax
        function PmaxValueChanged(app, event)
            pmax     = app.Pmax.Value;
            param    = app.ParameterButtonGroup.SelectedObject.Text;
            PlotType = app.PlotTypeButtonGroup.SelectedObject.Text; %deep,surf,prof
            if pmax <= app.Pmin.Value
                app.Pmax.BackgroundColor = app.colors.err;
                pause(0.5) % set error color & pause breifly
                app.Pmax.BackgroundColor = 'w';
                % reset to orginal - use param & plottype
                app.Pmax.Value = app.state.(param).(PlotType)(2);
            else % update Pmin volue for given param & plot type
                app.state.(param).(PlotType)(2) = pmax;
            end
            clear params PlotTypect pmax

            update_plot_data(app)
            update_plots(app) % update plots


        end

        % Selection changed function: ReferenceDataButtonGroup
        function ReferenceDataButtonGroupSelectionChanged(app, event)
            %update_anom(app);
            ref_str = app.ReferenceDataButtonGroup.SelectedObject.Text;
            param   = app.ParameterButtonGroup.SelectedObject.Text;
            app.state.(param).Ref = ref_str;
 
            % disable pH radio button for WOA or no good pH from the start
            if ~app.finfo.tf_pH || strcmp(ref_str,'WOA') 
                app.pHButton.Enable = 'Off';
            else
                app.pHButton.Enable = 'On';
            end

            update_anom(app);
            update_plot_data(app)
            update_plots(app) % update plots
            
        end

        % Selection changed function: ParameterButtonGroup
        function ParameterButtonGroupSelectionChanged(app, event)
            param    = app.ParameterButtonGroup.SelectedObject.Text;

            PlotType       = app.PlotTypeButtonGroup.SelectedObject.Text; %deep,surf,prof
            app.Pmin.Value = app.state.(param).(PlotType)(1);
            app.Pmax.Value = app.state.(param).(PlotType)(2);

            if strcmp(param, 'Oxygen') || strcmp(param, 'Nitrate') || ...
                    strcmp(param, 'pH')
                app.QCATable.Data = app.CGOD.(param); % revert to last saved
            else
                app.QCATable.Data = [];
            end

            if ~(strcmp(param, 'Nitrate') || strcmp(param, 'pH'))
                app.ReferenceDataButtonGroup.Enable = 'off'; % turn off
                app.DataAdjustmentsPanel.Enable     = 'off';
            else
                % enable all Reference Data panel objects
                set(findall(app.ReferenceDataButtonGroup, ...
                    '-property', 'enable'), 'enable', 'on') % turn on group

                % enable data adjustment panel objects
                set(findall(app.DataAdjustmentsPanel, ...
                    '-property', 'enable'), 'enable', 'on') % turn on panel

                %app.QCATable.Data = app.CGOD.(param); % revert to last saved
            end
   
            % only allow changes to occur for deep
            RB = findobj(app.DataAdjustmentsPanel,'Type','uibutton');
            if strcmp(PlotType, 'Deep')
                set(RB,'Enable','on')
            else
                set(RB,'Enable','off')
            end

            % pH SPECIFIC
            if strcmp(param ,'pH') % disable WOA radio button for pH
                app.WOAButton.Enable = 'off';
                if app.pH_PO.tf == 1
                    app.pHCpOffsetCorrectionButtonGroup.Enable = 1;
                end
            else
                app.pHCpOffsetCorrectionButtonGroup.Enable = 0;
            end

            % If no O2 limit O2 ref choices & set button to WOA
            if app.finfo.tf_Oxygen == 0 % O2 can't be used in refs
                app.ESPER_MIXButton.Enable = 'off';
                app.ESPER_NNButton.Enable  = 'off';
                app.ESPER_LIRButton.Enable = 'off';
                app.CANYONBButton.Enable   = 'off';
            end

            % IF MOVING TO different param reset any table edits
            %if strcmp(param,'Nitrate') | strcmp(param, 'pH')
                removeStyle(app.QCATable); % remove any styles applied o the table
                app.ApplyCelleditsButton.Enable = 'off';
                app.ApplyCelleditsButton.BackgroundColor = [0.96,0.96,0.96];
                app.CalculateAdjustmentsButton.BackgroundColor = [0.96,0.96,0.96];
                % app.QCATable.Data = app.CGOD.(param); % revert to last saved
            %end
             
            update_plot_data(app)
            update_plots(app)

        end

        % Value changed function: XOver_km
        function XOver_kmValueChanged(app, event)
            update_glodap(app)
            update_plot_data(app)
            update_plots(app) % update plots

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            
            if app.tf_OK == 0 % no file loaded yet
                return
            end
            
            tab_title = app.TabGroup.SelectedTab.Title;
            oldTab    = event.OldValue;
            
            %turn off radio button groups if pH pump offset tab is choosen
            allBGroups =  findobj(app.UIFigure,'type','uibuttongroup');

            if strcmp(tab_title,'pH pump offset') && app.pH_PO.tf == 0 % no offsets available
                
                alert_cell = {['There are no pH pump offsets! Reverting ',...
                    'to Previous tab'], 'WARNING!'};
                uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
                app.TabGroup.SelectedTab = oldTab;
                return

            elseif strcmp(tab_title,'pH pump offset')

                set(allBGroups,  'enable', 'off') % turn off button groups
                app.pHCpOffsetCorrectionButtonGroup.Enable = 1; % pH offset on. may n ot need this
            else
                set(allBGroups,  'enable', 'on') % turn on panel
            end

            %Check sensor senors again
            if app.finfo.tf_pH == 0
                app.pHButton.Enable = 'off';
                app.pHCpOffsetCorrectionButtonGroup.Enable = 'off';
            end

            %Check CpAct again to prevent pump offset buttons from re-activating upon new tab selection
            if app.pH_PO.tf == 0
                app.pHCpOffsetCorrectionButtonGroup.Enable = 0;
                % turn off all pH pump offset tab objects
                set(findall(app.pHpumpoffsetTab, '-property', 'enable'), ...
                    'enable', 'off')
            end

            if app.finfo.tf_Oxygen == 0
                app.OxygenButton.Enable = 'off';
            end

            update_plot_data(app)
            update_plots(app) % update plots
            
        end

        % Button pushed function: ShowmapButton
        function ShowmapButtonPushed(app, event)
            % generate map of float track. this is using Matlab functions,
            % but the mapping toolbox is not required (>R2018b)
            ftrack = app.finfo.ftrack; % SDN CYCLE LON LAT
            tC = ftrack(:,2) >= app.CycleMin.Value & ...
                ftrack(:,2) <= app.CycleMax.Value;
            ftrack = ftrack(tC,:);
            ftrack(:,3) = wrapTo180(ftrack(:,3));

            % % Check for meridian crossovers (ie 5906245), if found and nan row
            % meridianCrossover = any(abs([0; diff(ftrack(:,3))]) > 300);
            % if meridianCrossover

            f1 = figure(1);
            gax = geoaxes;
            geobasemap(gax, 'landcover');
            geoplot(gax, ftrack(:,4), ftrack(:,3),'ko-','MarkerSize',4);
            hold(gax,'on')

            %Make a seperate list of full gps locations (no missing lats or lons)
            known_locations = ftrack(all(~isnan(ftrack),2),:);

            %If GLODAP data exists, plot the positions of the casts on the map
            if ~isempty(app.glodap.data) 
                
                %Load in GLODAP data
                gdata = app.glodap.data;

                %Sort the GLODAP data by date to make things more organized
                [~,iDateSorted] = sort(gdata(:,2),'ascend');
                gdata = gdata(iDateSorted,:);
                
                %Create a list of date vectors from the GLODAP data for proper labelling of casts
                dvec  = datevec(gdata(:,app.glodap.I.SDN)); % for year coloring 

                %[unique data values;unique data starting indicies;group indicies]
                [~, iDates, dateGroups] = unique(dvec(:,1:2), 'rows');
                
                %If there are too many casts, the legend gets way too crowded, so limit this to the most recent 12 casts
                if length(iDates) > 24
                    disp('Substantial number of GLODAP casts! Limiting to the most recent!')

                    %Subset the most recent cast dates (this number of dates fits the map plot)
                    iDates = iDates(end-23:end);

                    %Subset the date groups by the new indicies that were just created above
                    finalDateGroup = dateGroups(end);
                    newStartDateGroup = finalDateGroup - 23;
                    newDateGroups = dateGroups>=newStartDateGroup & dateGroups<=finalDateGroup;
                    dateGroups = dateGroups(newDateGroups);
                    
                    %Subset GLODAP data for new dates
                    gdata = gdata(newDateGroups,:);

                    %Subset the date vectors for consistency when plotting
                    dvec  = datevec(gdata(:,app.glodap.I.SDN));
                    [~, iDates, dateGroups] = unique(dvec(:,1:2), 'rows');
                end

                %Create arrays of the unique months and years for all casts
                uMONTH = dvec(iDates,2);
                uYR    = dvec(iDates,1);

                %Create an empty cell array to store GLODAP datetimes to be used in the plot legend
                GLODAP_legend_dates = {};

                %Populate the cell array with the unique month/year dates of the casts (prevents overcrowding of the legend).
                for i = 1:length(iDates)
                    GLODAP_legend_dates = [GLODAP_legend_dates;sprintf('GLODAP %d/%d',uMONTH(i),uYR(i))];
                end

                %Assign each unique profile a combination an ID based on date, cruise, station, lat, and long
                [~, iCast, ~] = unique(gdata(:,[2 3 4 6 7]), 'rows');

                %Get the number of unique casts for GLODAP
                nCasts = length(iCast);

                %Create a colormap distributed over the number of years
                cmap  = parula(length(iDates));
                
                for i = 1:nCasts
                    %Retrieve the unique latitude and longitude of the cast
                    uLAT = gdata(iCast(i),6);
                    uLON = gdata(iCast(i),7);
                    
                    %Plot the cast but group the coloring of the plot based on the unique month and year (prevents the legend
                    %from getting too crowded with multiple casts in the same month/year).
                    Hm3(dateGroups(iCast(i))) = geoplot(gax,uLAT,uLON,'k^','MarkerSize',10,'MarkerFaceColor', cmap(dateGroups(iCast(i)),:));
                end
            end
            
            %Plot the beginning and end gps points as green and red, respectively
            Hm1 = geoplot(gax, known_locations(1,4), known_locations(1,3),'ko','MarkerSize',7,...
                  'MarkerFaceColor', app.colors.grn);
            Hm2 = geoplot(gax, known_locations(end,4), known_locations(end,3),'ko','MarkerSize',7,...
                  'MarkerFaceColor', app.colors.red);

            hold(gax,'off')
            gax.Title.String = app.finfo.wmo;
            gax.FontSize = 14; 

            %Add legend to distinguish first and last known locations
            if ~isempty(app.glodap.data)
                L3_cell = ['First';'Last';GLODAP_legend_dates];
                track_legend = legend([Hm1,Hm2,Hm3],L3_cell);
            else
                track_legend = legend([Hm1,Hm2],'First','Last');
            end
            track_legend.Location = 'northeastoutside';
        end

        % Button pushed function: FindChangePointsButton
        function FindChangePointsButtonPushed(app, event)
            % Use change point detection and BIC to determine change points
            % Only used for Nitrate or pH at this point
            % Correction for any segment = OFFSETn + DRIFTn*(T-Tn) where n
            %    is node n and (T-Tn) is the elapsed time in years starting
            %    from the cycle time at node n
            % Can only have a node where anomaly data exist
            % Drift can be calculated on the subset but applied to all
            %    profile data (missing at ref depth but exists shallower

            param = app.ParameterButtonGroup.SelectedObject.Text;

            tg  = strcmp(param,'Nitrate') | strcmp(param, 'pH');
            if ~tg
                app.QCATable.Data = [];
                fprintf(['FindChangePoints only allowed for nitrate ', ...
                    'or pH (not for %s)\n'],param)
                return
            end

            % ALL GOOD SO CONTINUE
            node_limit = 15;
            errorLim   = app.error_limits.(param); % pH or Nitrate
            % ref_str    = app.ReferenceDataButtonGroup.SelectedObject.Text;
            
            % an = raw cycle anomaly matrix for param, NaN's already removed
            % [SDN CYCLE LON LAT mean_anom std_anom]

            an   = app.pdata.mRDA; 

            % CHECK MISSING POSITIONS - if no reference data, no anoms
            tnan = isnan(an(:,3)); % missing lon so missing position
            if any(tnan)
                pat = repmat('%d,',1,sum(tnan));
                alert_cell = {sprintf(['Position fixes are missing for ', ...
                    'cycles: ',pat, ' leading to NaN values in the ', ...
                    'reference data and thus the anomally data. These ', ...
                    'values will be omitted during changepoint detection. ', ...
                    'Manual adjutment may be required'],an(tnan,2)),'Heads up!'};
                uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
                an = an(~tnan,:);
            end


            % CHECK FOR NaNs in anome array - remove since not needed
            tnan = isnan(an(:,5)); 
            if any(tnan)
                pat = repmat('%d,',1,sum(tnan));
                alert_cell = {sprintf(['Anommaly array contains NaNs for ', ...
                    'cycles: ',pat,' and will be removed prior to ', ...
                    'changepoint detection otherwise BIC values get set ',...
                    'to NaN'],an(tnan,2)),'Heads up!'};
                uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
                an = an(~tnan,:);
            end

            r_an = size(an,1); % row count
            
            Time0 = app.finfo.ftrack(1,1); % time of first profile
            X    = an(:,1); % cycle SDN's
            %YRS0 = (X-X(1))/365;  % elapsed years
            YRS0 = (X-Time0)/365;  % elapsed years
            
            % don't ever want too many change points.  Put a cap on iterations
            max_nodes = min([round(r_an/4-1), node_limit]); %limit to 15 nodes max!

            if max_nodes <1
                alert_cell = {['Sample size is too small.  Choose ', ...
                    'nodes manually.'], 'WARNING!'};
                uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
                return
            end      

            % Do some checks on the anom data
            tmpC = (an(1,2):an(r_an,2))'; % all possible anom cycles
            Cdif = setdiff(tmpC, an(:,2)); 
            if ~isempty(Cdif) % any missing cycles in anom subset?
                alert_cell = {sprintf(['All is good but the anomally array ', ...
                    'is not continuous & is missing cycles (shallow, bad,etc): ', ...
                    repmat('%d,',1,size(Cdif,1))], Cdif),'Heads up!'};
                uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
                if size(Cdif,1) / size(tmpC,1) >= 0.5
                    alert_cell = {['The anomally array is not continuous ', ...
                        '& is missing 50%% of cycles consider manual ', ...
                        'correction?'],'WARNING!'};
                    uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
                end            
            end

            % Find optimum # of change points in anom array
            % Use "SamplePoints" so don't need to be continuous
            % using more than 1 P level! How does that affect BIC calc
            BIC = nan(max_nodes, 3); %[nodes, bic, RMSE]
            for ct = 1:max_nodes
                field_str = sprintf('CHPT%02d',ct);

                [TF, CP_slope, CP_intcpt] = ischange(an(:,5),'linear', 1,...
                    'SamplePoints',YRS0,'MaxNumChanges',ct);
                
                chgpt_anom      = YRS0.*CP_slope + CP_intcpt; % change point anom array cycle avg
                % [~,Locb]        = ismember(sta,an(:,2)); % full data vs cycle avgerages
                % chgpt_anom_full = chgpt_anom(Locb); % chngpt anom for all  selected samples
                % resids          = raw - chgpt_anom_full - ref;

                resids          = an(:,5) - chgpt_anom;
                n               = length(resids);      
                SSR             = sum(resids.^2);
                chgpts          = sum(TF); % this should equal "ct"
                K               = (chgpts+1)*2 +1; % 1st +1 for cycle 1 M & B, 2nd +1 for ending B (M=0)
                %K               = (chgpts+1)*2 +2; % testing Sv1 approach
                bic             = log(SSR./n + errorLim.^2) + K*log(n) / n;
                BIC(ct,:)       = [chgpts, bic, sqrt(SSR./n)]; % Maybe round down? What is sig difference in BIC?
                
                % put each itteration in a structure
                d.(field_str).YRS0            = YRS0; 
                d.(field_str).TF              = TF; 
                d.(field_str).CP_slope        = CP_slope; 
                d.(field_str).CP_intcpt       = CP_intcpt; 
                d.(field_str).chgpt_anom      = chgpt_anom; 
                %d.(field_str).chgpt_anom_full = chgpt_anom_full; 
                d.(field_str).resids          = resids;  
                d.(field_str).SSR             = SSR; 
                d.(field_str).chgpts          = chgpts; 
                d.(field_str).n               = n; 
                d.(field_str).bic             = bic; 

                clear chgpt_anom chgpt_anom_full resids SSR chgpts
                clear bic Locb field_str TF CP_slope CP_intcpt
            end

            %save('C:\temp\Sv2_BIC_test.mat', 'BIC', 'd');

            % NOW GET MIN & and grab appropriate structure
            [~,idx] = min(BIC(:,2));

            %CHPTS = d.(sprintf('CHPT%02d',BIC(idx,1))); % BIC MIN
            CHPTS = d.(sprintf('CHPT%02d',idx)); % BIC MIN
            clear d

            % FINE TUNE FOR QC CGOD MATRIX
            CHPTS.TF([1,end])    = 1; % force starting and ending cycles as nodes 

            % Cheat a little here to generate the final offset (gain will be zero)
            % which is the correction which propagates past the last DM
            % cycle and set the ending slope to zero (don't propagate a
            % drift)
            CHPTS.CP_intcpt(end) = YRS0(end).*CHPTS.CP_slope(end) ...
                + CHPTS.CP_intcpt(end); 
            CHPTS.CP_slope(end) = 0; % Force ending slope = 0, no drift propagation   

            % GET THE CHANGE POINTS [YRS0, cycle, offset, slope]
            ischng_CHPTS = [YRS0(CHPTS.TF), an(CHPTS.TF,2), ...
                CHPTS.CP_intcpt(CHPTS.TF),  CHPTS.CP_slope(CHPTS.TF)]; 

            % DOES 1st QC NODE START ON MIN FLOAT CYCLE. IF NOT AUTO GENERATE A 1st NODE

            if an(1,2) ~= app.finfo.ftrack(1,2)
            %if an(1,2) ~= app.CycleMin.Value % start on starting cycle?
                % alert_cell = {sprintf(['The anomally array starting ' , ...
                %     'cycle (%d) does not match the the selected starting ',...
                %     'cycle (%d). A node will be added for the selected ', ...
                %     'starting cycle with a constant offset'], an(1,2), ...
                %     app.CycleMin.Value), 'Heads up!'};

                alert_cell = {sprintf(['The anomally array starting ' , ...
                    'cycle (%d) does not match the the float starting ',...
                    'cycle (%d). A node will be added for the float ', ...
                    'starting cycle with a constant offset'], an(1,2), ...
                    app.finfo.ftrack(1,2)), 'Heads up!'};
                uialert(app.UIFigure, alert_cell{1}, alert_cell{2});

                %t1 = app.finfo.ftrack(:,2) == app.CycleMin.Value;
                tmp = [app.finfo.ftrack(1,1:2), ischng_CHPTS(1,3), 0]; % line to add
                tmp(1,1) = (tmp(1,1) - Time0)/365;

                ischng_CHPTS = [tmp; ischng_CHPTS];

            end

            % ischange() intercepts are based on elapsed years from YRS0(1)
            % Our current QC approach resets time zero to the begining of
            % each segment so need to adjusts the ischange intercepts to
            % our time scheme Bn = M0 * YRS0(node n) + B0
            % Intercepts are based on Y0(1) need values at Y0(cycleX)
            ischng_CHPTS(:,3) = ...
                ischng_CHPTS(:,1).*ischng_CHPTS(:,4)+ischng_CHPTS(:,3);

            % save old for testing change. [cycle, gain, offset, drift]
            old_data = app.QCATable.Data; % cycle, gain, offset, drift, use to get gain

            new_data = [ischng_CHPTS(:,2), ... % update with new changes
                ones(size(ischng_CHPTS,1),1), ... % force gain = 1
                ischng_CHPTS(:,3:4)];

            new_data(:,2:4) = round(new_data(:,2:4),4); % round to QC list file precision


            % IS THERE A CHANGE IN THE TABLE DATA? 
            if numel(new_data) == numel(old_data) && ...
                    sum(abs(new_data(:)-old_data(:))) == 0
                fprintf('No change to data adjustments table were made!\n')
                % 
                % return
            end


            if BIC(idx,1) == node_limit % NOISY CORRECTION - POOR SENSOR PERFORMANCE?
                alert_cell = {['Auto node determination = node limit(%d) ', ...
                    'consider manual adjustment for this float - ', ...
                    'it is a noisy one!'],'WARNING!'};
                uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
            end

            % NOW UPDATE DATA & GUI - 
            app.CGOD.(param)   = new_data;
            app.QCATable.Data  = new_data;
            apply_QC_correction(app); % app.fdata updated
            update_anom(app)
            update_plot_data(app)
            update_QC_info(app)
            update_plots(app)


        end

        % Button pushed function: UPDATEQCLISTFILEButton
        function UPDATEQCLISTFILEButtonPushed(app, event)
            % REQUEST USER COMMENT ABOUT QC
            user_input = inputdlg('User QC comments',...
                'Please enter a comment here:',[1, 150]);

            if isempty(user_input) % no personal comment entered, exit
                alert_cell = {['A comment about the QC must be entered ',...
                    'by the user!'],'WARNING!'};
                uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
                return
            end

            % GET / SET SOME INFO
            cgod    = app.CGOD; % GUI modified corrections
            rcgod = size(cgod,1);
            qc_flds = fieldnames(cgod);
            Iflds   = fieldnames(app.fI); % index fieldnames
            tf      = ismember(Iflds,{'O' 'N' 'PH'}); 
            Iflds   = Iflds(tf); % subset index to SV2 correctable params
            % remove field names with empty values or  QC_info as field
            tg = true(size(qc_flds));
            for fld_ct = 1:size(qc_flds,1)
                if isempty(cgod.(qc_flds{fld_ct})) | ...
                        strcmp(qc_flds{fld_ct},'QC_info')
                    tg(fld_ct) = 0;
                end
            end
            qc_flds      = qc_flds(tg);

            wmo          = app.finfo.wmo;
            fmt          = '%d,%0.4f,%0.4f,%0.4f\r\n';
            %qc_info_flds = fieldnames(app.QC_info.Nitrate); 

             % use NO3 or pH to get param fields
             if any(strcmp(qc_flds,'Nitrate'))
                 qc_info_flds = fieldnames(app.QC_info.Nitrate);
             else
                 qc_info_flds = fieldnames(app.QC_info.pH);
             end

            % % CHECK THAT CGOD ADJUSTMENTS START ON FLOAT'S MINIMUM CYCLE
            % for ct = 1:numel(qc_flds)
            %     if ~(strcmp(qc_flds{ct},'Oxygen') || strcmp(qc_flds{ct},'Nitrate') ...
            %             || strcmp(qc_flds{ct},'pH'))
            %         continue
            %     end
            %     param = qc_flds{ct};
            % 
            %     if cgod.(param)(1,1) ~= app.finfo.ftrack(1,2)
            %         alert_str = sprintf(['The 1st cycle in the adjustment ', ...
            %             'matrix (%d) does not equal the 1st float cycle (%d)!',...
            %             'Please correct this before proceding'], ...
            %             cgod(1,1).(param), app.finfo.ftrack(1,2));
            %         alert_cell = {alert_str,'WARNING!'};
            %         uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
            %         return
            %     end
            % end
             

            % VERYIFY THAT CGOD STARTING & ENDING NODES MATCH FOR NO3 & pH
            % get float data for plotting
            for ct = 1:numel(qc_flds)
                param   = qc_flds{ct};

                if ~any(ismember({'Nitrate' 'pH'},param))
                    continue
                end
                
                tf_Ifld = strncmpi(Iflds, param(1),1); % match 1st letter
                ind     = app.fI.(Iflds{tf_Ifld});
                tg      = ~isnan(app.fdata(:,ind)) | app.fdata(:,ind+1) ~= 4; % good param data
                [c_min, c_max] = bounds(app.fdata(tg,app.fI.STA));

                % Now test for cycle range compliance

                % min cycle test usually caught in table edit callback but
                % in here for redundancy, uncomment if needed

                % if cgod.(param)(1,1) ~= c_min
                %     alert_str = sprintf(['The 1st cycle in the adjustment ', ...
                %         'matrix (%d) does not equal the 1st %s cycle (%d)!',...
                %         'Please correct this before proceding'], ...
                %         cgod.(param)(1,1), param, c_min);
                %     alert_cell = {alert_str,'WARNING!'};
                %     uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
                %     return
                % end
                rcgod = size(cgod.(param),1);
                % Catches case where user might be experimenting and forgot
                % toset the cycle range back to max extent
                if cgod.(param)(rcgod,1) ~= c_max
                    alert_str = {sprintf(['The last cycle in the adjustment ', ...
                        'matrix (%d) does not equal the last %s cycle (%d)!'],...
                        cgod.(param)(rcgod,1), param, c_max), ...
                        'Is this intentional?'};
                    str = uiconfirm(app.UIFigure, alert_str,'Confirm QC matrix', ...
                        'Icon','warning','Options',{'Yes' 'Cancel'});

                    if strcmp(str,'Cancel')
                        return
                    end
                end
            end

            % Put qc_info_field formats in a cell
            % UserName  Date  Ref  PresRange  NodeCt  BIC  RMSE
            for ct = 1:numel(qc_info_flds)
                if regexp(qc_info_flds{ct},'UserName|Date|Ref','once')
                    info_fmts{ct} = [qc_info_flds{ct},',%s,'];
                elseif regexp(qc_info_flds{ct},'PresRange','once')
                    info_fmts{ct} = [qc_info_flds{ct},',[%0.0f %0.0f],'];
                elseif regexp(qc_info_flds{ct},'NodeCt','once')
                    info_fmts{ct} = [qc_info_flds{ct},',%0.0f,'];
                elseif regexp(qc_info_flds{ct},'BIC','once')
                    info_fmts{ct} = [qc_info_flds{ct},',%0.2f,'];
                elseif regexp(qc_info_flds{ct},'RMSE','once')
                    info_fmts{ct} = [qc_info_flds{ct},',%0.2e\r\n'];
                end
            end

            qclist_fp  = app.QC_info.qclist_fp; % QC list file path (may not exist yet)
            qclist_fp2 = qclist_fp ; 
            %qclist_fp2 = regexprep(qclist_fp,'.txt', '_JP.txt'); % for testing

            % CHECK FOR EXISTING FILE AND PULL INFO INTO CELL ARRAY
            old_list = {};
            if isfile(qclist_fp)
                old_list = readcell(qclist_fp,'Delimiter','\r\n');
            end

            % OPEN FILE AND PRINT NEW CORRECTIONS & META INFO
            fid = fopen(qclist_fp2,'w+'); % open for printing & overwrite existing
            fprintf(fid,'%s\t%s\tSAGE version %s\r\n',wmo, ...
                datestr(now,'mm/dd/yy HH:MM'), app.info.version);
            fprintf(fid,'QC comment,%s\r\n', user_input{1});

            % BUILD PARAMETER/VALUE PAIR LISTINGS IF NO3 or pH EXIST
            % OTHERWISE REMOVE PARAM FROM LIST TO UPDATE
            % adjustmemt meta strings- probably a better way...
            % if no sensor remove field so no action on it

            % Nitrate QC meta line
            if app.finfo.tf_Nitrate == 1 % Nitrate
                param = 'Nitrate';
                % if no recent update to QC, check for previous and add if
                % possible
                if strcmp(app.QC_info.(param).Ref,'???Ref???') && ...
                        isfield(app.QCA.QC_info, param)
                    app.QC_info.(param) = app.QCA.QC_info.(param);
                end

                %if ~strcmp(app.QC_info.(param).Ref,'???Ref???')
                    fprintf(fid,'QC parameter,%s,',param);
                    for ct = 1:numel(qc_info_flds)
                        tf = strcmp(qc_info_flds, qc_info_flds{ct});
                        fprintf(fid, info_fmts{ct}, ...
                            app.QC_info.(param).(qc_info_flds{tf}));
                    end
                %end
                DM_opperator = app.QC_info.(param).UserName;
            else
                qc_flds = qc_flds(~strcmp(qc_flds,'Nitrate'));
            end

            % pH QC meta line
            if app.finfo.tf_pH == 1 %pH
                param = 'pH';
                % if no recent update to QC, check for previous and add if
                % possible
                if strcmp(app.QC_info.(param).Ref,'???Ref???') && ...
                        isfield(app.QCA.QC_info, param)
                    app.QC_info.(param) = app.QCA.QC_info.(param);
                end

                %if ~strcmp(app.QC_info.(param).Ref,'???Ref???')
                    fprintf(fid,'QC parameter,%s,',param);
                    for ct = 1:numel(qc_info_flds)
                        tf = strcmp(qc_info_flds, qc_info_flds{ct});
                        fprintf(fid, info_fmts{ct}, ...
                            app.QC_info.(param).(qc_info_flds{tf}));
                    end
                %end
                DM_opperator = app.QC_info.(param).UserName;
            else
                qc_flds = qc_flds(~strncmp(qc_flds,'pH',2));
            end

            % Print correction lines
            for ct = 1: size(qc_flds,1)
                if strcmp(qc_flds{ct},'pH_offset')
                    fprintf(fid,[qc_flds{ct},',%s\r\n'], cgod.(qc_flds{ct})');
                else
                    % transpose correction matrix & print colums with format
                    fprintf(fid,[qc_flds{ct},',',fmt], cgod.(qc_flds{ct})');
                end
            end
            

            % ADD PREVIOUS CORRECTIONS IF THE EXIST
            if ~isempty(old_list)
                break_str = 'PREVIOUS';
                fprintf(fid,'\r\n%s\r\n', break_str); % add speration too
                for ct = 1:size(old_list,1)

                    %For older floats (pre 2016), some early QC list entries have a line for the WMO, which gets
                    %read in as a number. This block converts it to a character string to prevent error in the next
                    %block.
                    if ~isstring(old_list{ct})
                        old_list{ct} = char(old_list{ct});
                    end
                    if contains(old_list{ct}, break_str)
                        fprintf(fid,'\r\n%s\r\n', break_str); % add seperation back
                    else
                        fprintf(fid,'%s\r\n',old_list{ct});
                    end
                end
            end
            fclose(fid);

            % ************************************************************
            % NEXT UPDATE THE QC LIST LOG FILE
            % UserName  Date  Ref  PresRange  NodeCt  BIC  RMSE

            % fprintf(fid,'No recent updates!\r\n');

            if ~isempty(app.finfo.MBARI_ID)
                str1 = sprintf('%s\t%s\t%s\t%s\tSAGE Version %s\t', app.finfo.wmo, ...
                    app.finfo.MBARI_ID, datestr(now,'mm/dd/yy HH:MM'), ...
                    DM_opperator, app.info.version);
            else
                str1 = sprintf('%s\t%s\t%s\tSAGE Version: %s\t', app.finfo.wmo, ...
                   datestr(now,'mm/dd/yy HH:MM'), ...
                    DM_opperator, app.info.version);
            end

            % NOW ADD PARAMETER SPECIFIC INFO
            if app.finfo.tf_Nitrate == 1 % NITRATE
                param   = 'Nitrate';
                qc_info = app.QC_info.(param);

                if strcmp(qc_info.Ref,'???Ref???') % NO update
                    %str2 = sprintf(' No recent %s updates!\r\n', param);
                    str2 = sprintf(' No recent %s updates!\r\n', param);
                else
                    str2    = sprintf('%s vs %s at [%0.0f %0.0f] dbar.', ...
                        param, qc_info.Ref, qc_info.PresRange);
                    if cgod.(param)(1,2) ~=1
                        str2 = sprintf(' %s %0.3f Nitrate gain applied.',...
                            str2,cgod.(param)(1,2));
                    end
                end
            else
                str2 =' ';
            end

            if app.finfo.tf_pH == 1 % pH
                param   = 'pH';
                qc_info = app.QC_info.(param);

                if strcmp(qc_info.Ref,'???Ref???') % NO update
                    str3 = sprintf(' No recent %s updates!', param);
                else
                    str3    = sprintf(' %s vs %s at [%0.0f %0.0f] dbar.', ...
                        param, qc_info.Ref, qc_info.PresRange);
                    if ~strcmp(cgod.pH_offset,'none')
                        str3 = sprintf([' %s pH pump offset correction applied ',...
                            'using %s method.'],str3, cgod.pH_offset);
                    end
                end
            else
                str3 = '';
            end

            % UPDATE LOG FILE
            fid = fopen(fullfile(app.dirs.QClog,app.dirs.QClog_fn),'a+');
            fprintf(fid,'%s%s%s %s\r\n',str1, str2, str3, user_input{1});
            fclose(fid);

        end

        % Button pushed function: SHOWQCHISTORYButton
        function SHOWQCHISTORYButtonPushed(app, event)
            f1       = uifigure;
            name_str = sprintf('QC HISTORY FOR: %s', app.finfo.wmo);
            if isfield(app.finfo,'MBARI_ID')
                name_str = sprintf('%s (%s)', name_str, app.finfo.MBARI_ID);
            end
            f1.Name = name_str;
            txa     = uitextarea(f1,'Editable', 0,'WordWrap',1);
            f1pos   = f1.InnerPosition;
            txa.Position = [1 1 f1pos(3:4)];
            txa.Value    = app.QC_history;

        end

        % Cell edit callback: QCATable
        function QCATableCellEdit(app, event)
            indices   = event.Indices;
            newData   = event.NewData; % should be size 1x1
            oldData   = event.PreviousData;
            tableData = app.QCATable.Data;
            param     = app.ParameterButtonGroup.SelectedObject.Text;
            an        = app.pdata.mRDA; % [SDN CYCLE LON LAT mean_anom std_anom]

            % Do I need to recreate every time, maybe store as property?
            %s1 = uistyle('BackgroundColor','yellow');

            % DO SOME VALIDATION CHECKS
            tg  = strcmp(param,'Nitrate') | strcmp(param, 'pH');
            if ~tg
                str = sprintf(['QC corrections can only be applied to ', ...
                      'Nitrate or pH at this time and not to %s'],param);
                disp(str);
                app.QCATable.Data(indices(1),indices(2)) = oldData;
                uialert(app.UIFigure, str, 'WARNING!');
                return
            end

            % check ending drift & force to zero if needed
            if tableData(end,4) ~= 0
                app.QCATable.Data(end,4) = 0;
                fprintf('The final node drift was set to zero!\n')
            end

            % cycle value can't be greater than max
            if indices(2) == 1 && newData > app.finfo.CycleRange(2)
                app.QCATable.Data(indices(1),indices(2)) = oldData;
                fprintf(['The cycle range input must be less than the ',...
                    'max float cycle!\n'])
            end

            % can only supply a gain for Nitrate at this point!
            if indices(2) == 2 && ~strcmp(param,'Nitrate')
                app.QCATable.Data(indices(1),indices(2)) = oldData;
                fprintf(['Gain values not equal to 1 are not allowed for ',...
                    '%s\n'],param)
            end

            % NOW MAKE SURE QC NODES START ON MIN CYCLE. IF IT DOESN'T AUTO
            % GENERATE A 1st NODE
            if tableData(1,1) ~= app.finfo.ftrack(1,2)
            %if tableData(1,1) ~= app.CycleMin.Value
                alert_cell = {sprintf(['The adjustments table starting ' , ...
                    'cycle (%d) does not match the the float starting ',...
                    'cycle (%d). A node will be added for the float ', ...
                    'starting cycle with a constant offset'], ...
                    tableData(1,1), app.finfo.ftrack(1,2)), ...
                    'WARNING!'};

                % alert_cell = {sprintf(['The adjustments table starting' , ...
                %     'cycle (%d) does not match the the selected starting ',...
                %     'cycle (%d). A node will be added for the selected ', ...
                %     'starting cycle with a constant offset'], ...
                %     tableData(1,1), app.CycleMin.Value), ...
                %     'WARNING!'};
                uialert(app.UIFigure, alert_cell{1}, alert_cell{2});


                tableData = [app.CycleMin.Value, tableData(1,2:3), 0; tableData];
                app.QCATable.Data = tableData;
                indices(1) = indices(1) +1; % change index for added starting row
                addStyle(app.QCATable,app.uistyles.s1 ,'row', 1);
            end


            str = ''; % re-initialize
            if isnan(newData)
                app.QCATable.Data(indices(1),indices(2)) = oldData;
                str = 'Cell entry in table was not numeric - please try again';
                disp(str);
            elseif newData == oldData
                app.QCATable.Data(indices(1),indices(2)) = oldData;
                str = 'New cell entry equals old - so no change';
                disp(str);
            elseif indices(2) == 1 && ~ismember(newData,an(:,2))
                app.QCATable.Data(indices(1),indices(2)) = oldData;
                str = ['No anomaly data exist for entered cycle node! Please ',...
                    'choose a different cycle'];
                disp(str);
            end

            if ~isempty(str) % Did any validations fail?
                uialert(app.UIFigure, str, 'WARNING!');
                return
            end

            % INPUTS ALL GOOD IF YOU GET TO HERE


            % Only 1 gain value allowed for entire column, and only for nitrate
            % adjust if needed and add style to highlight change
            if indices(2) == 2
                app.QCATable.Data(:,2) = newData;
                addStyle(app.QCATable, app.uistyles.s1,'column',indices(2));
            else
                addStyle(app.QCATable, app.uistyles.s1,'cell', indices);
            end

            % CHANGES VALIDATED BUT WON'T BE ACCEPTED / ACTED UPON UNTIL
            % BUTTON PRESSED ( CALC ADJ or APPLY CELL EDITS)
            app.ApplyCelleditsButton.Enable = 'on';
            app.ApplyCelleditsButton.BackgroundColor = 'y';
            app.CalculateAdjustmentsButton.BackgroundColor = 'y';

        end

        % Button pushed function: CalculateAdjustmentsButton
        function CalculateAdjustmentsButtonPushed(app, event)
            % calculate the slope and offset using only the nodes & gain
            % in the adjuatments table
            param = app.ParameterButtonGroup.SelectedObject.Text;

            % VALIDATE PARAM
            tg    = strcmp(param,'Nitrate') | strcmp(param, 'pH');
            if ~tg
                str = sprintf(['QC corrections can only be applied to ', ...
                    'Nitrate or pH at this time and not to %s'], param);
                disp(str);
                uialert(app.UIFigure, str, 'WARNING!');
                return
            end

            % CALCULATE CORRECTIONS
            tableData   = app.QCATable.Data; % only using cycles & gain, determine the rest
            cgod        = tableData * NaN; % predim
            r_cgod      = size(cgod,1);
            cgod(:,1:2) = tableData(:,1:2); % add cycles & gain from table data- these are fixed
            errorLim    = app.error_limits.(param); % pH or Nitrate
            dC          = [diff(cgod(:,1));1]; % cycle diffs, use to limit slope application
            
            % an raw cycle anoms matrix for param [SDN cycle lon lat mean std]
            % any NaN anoms have already been removed
            an   = app.pdata.mRDA; 
            raw  = app.pdata.RD; % Needed to adjust anom for regr if gain ~=0
            ref  = app.pdata.REF;% Needed to adjust anom for regr if gain ~=0
            sta  = app.pdata.STA;

            % If table gain not == 1 for nitrate, anom values needs to be
            % adjusted before regression segment analysis occurs
            % anom = Ref/gain - raw = YRS0*M +B
            if tableData(1,2) ~= 1 && strcmp(param,'Nitrate')
                tmp_anom = raw - ref.* tableData(1,2); % gain adjusted
                m_anom = NaN(size(an,1),2); % predim mean, std
                for ct = 1:size(an,1)
                    t1 = sta == an(ct,2);
                    m_anom(ct,:) = [mean(tmp_anom(t1),'omitnan'), ...
                        std(tmp_anom(t1),[],'omitnan')];
                end

                an(:,5:6) = m_anom;
                fprintf(['A nitrate gain adjustment has been applied to ',...
                    'the anomally prior to segment regression ',...
                    'calculations\n'])
                clear raw ref sta tmp_anom m_anom ct
            end

            for ct = 1:r_cgod
                if ct < r_cgod
                    t1 = an(:, 2) >= cgod(ct,1) &  ... % get block
                        an(:, 2) < cgod(ct+1,1);
                else
                    t1 = an(:, 2) >= cgod(ct,1);
                end

                if sum(t1) == 0 % no data for node block - should not happen!
                    fprintf('No data for node block\n');
                    continue
                end

                X     = an(t1,1); % cycle SDN's
                YRS0  = (X-X(1))/365;  % elapsed years
                anoms = an(t1,5); % avg anomaly time series

                % single step or last cycle, slope = 0
                if dC(ct) == 1 
                    cgod(ct,3) = round(anoms(1),4); % offset at start of block
                    cgod(ct,4) = 0; % force slope to zero
                    fprintf('Single step in QC node - forcing slope to zero\n');

                % not enough data to test slope (need 3 or more)
                elseif dC(ct) == 2
                    coefs = polyfit(YRS0, anoms,1);
                    if abs(diff(anoms)) > errorLim % change > sensor error 
                        cgod(ct,3) = round(coefs(2),4);
                        cgod(ct,4) = round(coefs(1),4);
                        fprintf(['Node step = 2 & 2 point diff is > error ',...
                            'limits. Using slope but can not test significance\n']);
                    else % change <= sensor error, use simple avg & no slope
                        cgod(ct,3) = round(mean(anoms),4);
                        cgod(ct,4) = 0;
                        fprintf(['Node step = 2 & 2 point diff is < error ',...
                            'limits. Setting slope =0\n']);
                    end
                
                else % linear fit with slope test
                    % test slope differnt from zero, structure returns all
                    % data needed

                    T = test0slope_sageV2(YRS0,anoms,app.sig_level); 
                    
                    if isfield(T,'test') && T.test == 1 % significant so add m & b to cgod matrix
                        fprintf('Slope Test is signigicant!\n')
                        cgod(ct,3) = round(T.by,4);
                        cgod(ct,4) = round(T.my,4);
                        fprintf('Slope is signifcant and will be used!\n');                      
                    else
                        cgod(ct,3) = round(median(anoms,'omitnan'),4);
                        cgod(ct,4) = 0; % force zero, slope not differnet from zero
                        fprintf('Slope is NOT different from zero, Settinge slope = 0!\n');      
                    end
                    %disp([ct dC(ct) cgod(ct,3) cgod(ct,4)])
                end
            end

            app.QCATable.Data = cgod; % update table data

            app.CGOD.(param) = cgod; % Adjustments updated
            
            % UPDATE!
            apply_QC_correction(app); % app.fdata updated
            update_anom(app)
            update_plot_data(app) 
            update_QC_info(app)
            update_plots(app) 


        end

        % Button pushed function: ApplyCelleditsButton
        function ApplyCelleditsButtonPushed(app, event)
            param = app.ParameterButtonGroup.SelectedObject.Text;
            tg    = strcmp(param,'Nitrate') | strcmp(param, 'pH');
            if ~tg
                fprint(['QC corrections can only be applied to Nitrate or',...
                    'pH at this time and not to %s\n'],param);
                return
            end

            app.CGOD.(param) = app.QCATable.Data; % update from table cell edits

            apply_QC_correction(app) % update app.fdata matrix
            update_anom(app)
            update_plot_data(app)
            update_QC_info(app)
            update_plots(app)

        end

        % Button pushed function: ReloadinitialParameterdataButton
        function ReloadinitialParameterdataButtonPushed(app, event)

            param = app.ParameterButtonGroup.SelectedObject.Text;

            if ~( strcmp(param,'Nitrate') || strcmp(param, 'pH') )
                fprint(['Reloading inital data is only allowed for ',...
                    'Nitrate or pH at this time and not to %s\n'],param);
                return
            end
            
            app.CGOD.(param)  = app.QCA.(param); % previous adjustments
            app.QCATable.Data = app.QCA.(param);
            %app.fdata         = app.fdata_orig;

            % reset nitrate data to original
            if strcmp(param,'Nitrate') && app.finfo.tf_Nitrate == 1
                iN   = [app.fI.N, app.fI.NA]; % NO3 indices for float data
                app.fdata(:,iN)         = app.fdata_orig(:,iN);
            end

            % reset nitrate data to original, If pH, also update to propper
            %pH pump offset correction mode button
            if strcmp(param, 'pH') && app.finfo.tf_pH == 1
                iPH   = [app.fI.PH, app.fI.PHA]; % pH indices for float data
                app.fdata(:,iPH)         = app.fdata_orig(:,iPH);

                offset_objs = app.pHCpOffsetCorrectionButtonGroup.Children;
                tTXT = strcmp({offset_objs(:).Text}, app.QCA.pH_offset);
                if any(tTXT)
                    app.pHCpOffsetCorrectionButtonGroup.SelectedObject = ...
                        offset_objs(tTXT);
                else
                    app.pHCpOffsetCorrectionButtonGroup.SelectedObject = ...
                        offset_objs(1); % none
                end
            end

            update_anom(app)
            update_plot_data(app)
            update_plots(app)

            removeStyle(app.QCATable)
            app.ApplyCelleditsButton.Enable = 'off';
            app.ApplyCelleditsButton.BackgroundColor = [0.96,0.96,0.96];
            app.CalculateAdjustmentsButton.BackgroundColor = [0.96,0.96,0.96];


        end

        % Button pushed function: InsertRowButton
        function InsertRowButtonPushed(app, event)
            tableData = app.QCATable.Data;
            rc        = size(tableData);
            selection = app.QCATable.Selection;
            if isempty(selection)
                rr = rc(1);
                app.QCATable.Data = [tableData; ...
                    tableData(rc(1),1)+1, tableData(1,2), 0, 0];

            else
                rr = selection(1,1); % first row indice in selection
                app.QCATable.Data = [tableData(1:rr,:); ...
                    tableData(rr,1)+1, tableData(1,2), 0, 0; ...
                    tableData(rr+1:rc(1),:)];
            end

            % addStyle(app.QCATable, app.uistyles.s1,'row', ...
            %     size(app.QCATable.Data,1));
             addStyle(app.QCATable, app.uistyles.s1,'row', ...
                rr+1);         

            app.ApplyCelleditsButton.Enable = 'on';
            app.ApplyCelleditsButton.BackgroundColor = 'y';
            app.CalculateAdjustmentsButton.BackgroundColor = 'y';


        end

        % Button pushed function: DeleteRowButton
        function DeleteRowButtonPushed(app, event)
            tableData         = app.QCATable.Data;
            rc                = size(tableData);

            selection = app.QCATable.Selection;

            if rc(1) ==  1
                app.QCATable.Data = [1 1 0 0];
            elseif isempty(selection)
                app.QCATable.Data = tableData(1:rc(1)-1,:);
            else
                rr = selection(:,1); % all selected row indices
                tableData(rr,:) = [];
                app.QCATable.Data = tableData;
            end

        end

        % Selection changed function: pHCpOffsetCorrectionButtonGroup
        function pHCpOffsetCorrectionButtonGroupSelectionChanged(app, event)
            corr_type = app.pHCpOffsetCorrectionButtonGroup.SelectedObject.Text;

            % update correction type info
            app.CGOD.pH_offset = corr_type;

            % updata fdata with proper raw pH values
            tf_col            = contains(app.pH_PO.hdr, corr_type(1:3));
            app.fdata(:,app.fI.PH) = app.pH_PO.data(:,tf_col);
            fprintf(['Raw pH was replaced with offset corrected pH ', ...
                'using the %s estimate.\n'], app.CGOD.pH_offset);

            % NO CHANGE TO CGOD
            apply_QC_correction(app) % update app.fdata matrix
            update_anom(app)
            update_plot_data(app)
            %update_QC_info(app)
            update_plots(app)


        end

        % Size changed function: pHpumpoffsetTab
        function pHpumpoffsetTabSizeChanged(app, event)
            % use this to do a more graceful job of resizing the colorbar
            % which is colored by cycle number
            % LBWH
            %position = app.pHpumpoffsetTab.Position;
            IP = app.pHpumpoffsetTab.InnerPosition; % Drawable area, pixes
            marg = 0.1; % fraction


            % cycle vs offset
            app.UIAxes_CyclePO.InnerPosition = [ ...
                IP(1) + IP(3)*(marg),... % L
                IP(2) + IP(4)*0.70,... % B
                IP(3) - 2* IP(3)*marg,... % W
                IP(4)*(1-0.70-marg/2)];   % H

            % raw pH vs P
            app.UIAxes_ProfNoCor.InnerPosition = [ ...
                IP(1) + IP(3)*(marg),... % L
                IP(2) + IP(4)*marg,... % B
                (IP(3) - 4* IP(3)*marg)/2,... % W
                IP(4)*(.40)];   % H

            % raw pumpoffset corrected pH vs P
            app.UIAxes_ProfPOCor.InnerPosition = [ ...
                IP(1) + IP(3) - 4* IP(3)*marg,... % L
                IP(2) + IP(4)*marg,... % B
                (IP(3) - 4* IP(3)*marg)/2,... % W
                IP(4)*(.40)];   % H

            % colar bar colored by cycle number
            app.UIAxes_Hidden4CB.InnerPosition = [ ...
                IP(1) + IP(3)*(marg),... % L
                IP(4)*(.56),... % B
                IP(3) - 2* IP(3)*marg,... % W
                marg];   %H
            a%pp.pHpumpoffsetTab.Visible = 'on';


        end

        % Size changed function: QCTab, RAWTab
        function QCTabSizeChanged(app, event)
            % use this to do a more graceful job of resizing 
            % RAW & QC TABS
            PlotType   = app.PlotTypeButtonGroup.SelectedObject.Text; %deep,surf,prof

            axQC   = findall(app.QCTab,'Type','Axes');
            axRAW  = findall(app.RAWTab,'Type','Axes');
            ax_pos = NaN(size(axQC,1),4); % can be used for both RAW & QC
            
            IP = app.QCTab.InnerPosition; % Drawable area, pixes works for both
            L=IP(1); B=IP(2); W=IP(3); H=IP(4); %  inner dimmensions
            marg = 0.1; % fraction

            if ~strcmp(PlotType,'Profile') % Surface or Deep
                ax_pos(:,3)     = (W - marg*W*3)/2;                % surf & deep fig width's, oh for colorbar
                ax_pos(1:4,4)   = (H - marg*H*3)/2;                % surf & deep fig height's
                ax_pos([1,3],1) = L + W*marg;                      % L subplots 1,3
                ax_pos([2,4],1) = L + W*marg*2 + (W - marg*W*3)/2; % L subplots 2,4
                ax_pos([3,4],2) = B + H*marg;                      % B subplots 3,4
                ax_pos([1,2],2) = B + H*marg*2 + (H - marg*H*3)/2; % B subplots 1,1
                for ct = 1:4
                    axQC(ct).InnerPosition = ax_pos(ct,:);
                    axRAW(ct).InnerPosition = ax_pos(ct,:);
                end

            else % Profile plots
                ht_scale = 0.9; % reduce heights by this fraction
                ax_pos(:,3)     = (W - marg*W*3)/2;                % profile fig width's, oh for colorbar
                ax_pos(1:4,4)   = (H - marg*H*3)/2*ht_scale;       % profile fig height's
                ax_pos([1,3],1) = L + W*marg;                      % L subplots 1,3
                ax_pos([2,4],1) = L + W*marg*2 + (W - marg*W*3)/2; % L subplots 2,4
                ax_pos([3,4],2) = B + H*marg;                      % B subplots 3,4
                ax_pos([1,2],2) = B + H*marg*2 + ax_pos(1,4) ; % B subplots 1,1
                for ct = 1:5
                    if ct < 5
                    axQC(ct).InnerPosition = ax_pos(ct,:);
                    axRAW(ct).InnerPosition = ax_pos(ct,:);
                    else % hidden axis for colorbar
                        axQC(ct).InnerPosition = [ax_pos(1,1), ...
                            ax_pos(1,2)+ax_pos(1,4)*1.3, ax_pos(1,3), ...
                            ax_pos(1,4)*marg];
                            
                        axRAW(ct).InnerPosition = axQC(ct).InnerPosition;
                    end
                end
            end
            
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            L = app.UIFigure.InnerPosition(1); 
            B = app.UIFigure.InnerPosition(2);   
            W = app.UIFigure.InnerPosition(3);  
            H = app.UIFigure.InnerPosition(4); %  inner dimmensions
            marg = 0.01; % fraction
            w = (W - marg*W*3); % margin corrected working width
            h = (H - marg*H*2); % margin corrected working height
            ratio = 1220 / W; % 1120 is initial UI figure width set at design time
            wt = 0.3;
            if ratio < 1 % as zoom gets wider decreas control panel width
                wt = wt .* (1120./W);
            end
            % disp([W wt])

            % app.UIFigure.Visible = 'off';
            app.SAGEV2Panel.OuterPosition = [W*marg, H*marg, w*wt, h]; % graphics
            app.Panel.OuterPosition       = [W*marg*2+w*wt, H*marg, w*(1-wt), h]; % controls
            % app.UIFigure.Visible = 'on')

            %jp = app.UIFigure.InnerPosition
            
            
        end

        % Button pushed function: UPDATEMBARIODVButton
        function UPDATEMBARIODVButtonPushed(app, event)
            % ************************************************************************
            % FOR MBARI REEPROCESS THE FLOAT HERE THIS IS MORE OF A CHECK
            % THE USER CAN THE RELOAD THE FLOAT TO SEE IF THE CORRECTIONS
            % MATCH
            if app.tf_OK == 0
                alert_cell = {'Please load a float before reprocessing! ', ...
                    'WARNING!'};
                uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
                return
            end
            % 
            % jp1 = app.finfo;
            % save('C:\temp\SV2_test.mat','jp1') % TESTING


            if isfield(app.mlist,'list')
                old_text = app.SelectfileButton.Text;

                app.SelectfileButton.Text = 'Reprocessing MBARI float...';
                app.SelectfileButton.BackgroundColor = app.colors.ora;
                drawnow

                [tf_float,~] = Process_APEX_float(app.finfo.MBARI_ID, ...
                    app.dirs, 'all', 0);
            
                if tf_float.status == 1
                    app.SelectfileButton.Text = 'Updating ODV LR files...';
                    tf_odv = argo2odv_LIAR(app.finfo.wmo, app.dirs, ...
                        'all', 0);
                else
                    app.SelectfileButton.BackgroundColor = app.colors.err;
                    alert_cell = {'Reprocessing unsuccessful! ', ...
                        'WARNING!'};
                    uialert(app.UIFigure, alert_cell{1}, alert_cell{2});
                    return
                end

                if tf_odv
                    app.SelectfileButton.BackgroundColor = app.colors.tur;
                    app.SelectfileButton.Text =  sprintf('%s REPROCESSED!',...
                        old_text);
                    drawnow
                else

                end
            end
        end

        % Button down function: UIAxes_QCCycleData
        function UIAxes_QCCycleDataButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.SAGEV2Panel = uipanel(app.UIFigure);
            app.SAGEV2Panel.Title = 'SAGE V2';
            app.CycleRangePanel = uipanel(app.SAGEV2Panel);
            app.CycleRangePanel.Title = 'Cycle Range';
            app.Label = uilabel(app.CycleRangePanel);
            app.Label.Text = '';
            app.CycleMin = uieditfield(app.CycleRangePanel, 'numeric');
            app.CycleMin.ValueDisplayFormat = '%.0f';
            app.CycleMin.ValueChangedFcn = createCallbackFcn(app, @CycleMinValueChanged, true);
            app.EditField_2Label = uilabel(app.CycleRangePanel);
            app.EditField_2Label.Text = '';
            app.CycleMax = uieditfield(app.CycleRangePanel, 'numeric');
            app.CycleMax.ValueDisplayFormat = '%.0f';
            app.CycleMax.ValueChangedFcn = createCallbackFcn(app, @CycleMaxValueChanged, true);
            app.PressureRangedbarPanel = uipanel(app.SAGEV2Panel);
            app.PressureRangedbarPanel.Title = 'Pressure Range (dbar)';
            app.EditField_3Label = uilabel(app.PressureRangedbarPanel);
            app.EditField_3Label.Text = '';
            app.Pmin = uieditfield(app.PressureRangedbarPanel, 'numeric');
            app.Pmin.ValueDisplayFormat = '%.0f';
            app.Pmin.ValueChangedFcn = createCallbackFcn(app, @PminValueChanged, true);
            app.Pmin.Value = 1280;
            app.EditField_4Label = uilabel(app.PressureRangedbarPanel);
            app.EditField_4Label.Text = '';
            app.Pmax = uieditfield(app.PressureRangedbarPanel, 'numeric');
            app.Pmax.ValueDisplayFormat = '%.0f';
            app.Pmax.ValueChangedFcn = createCallbackFcn(app, @PmaxValueChanged, true);
            app.Pmax.Value = 1520;
            app.GlodapCrossoverkmPanel = uipanel(app.SAGEV2Panel);
            app.GlodapCrossoverkmPanel.Title = 'Glodap Crossover (km)';
            app.EditField_5Label = uilabel(app.GlodapCrossoverkmPanel);
            app.EditField_5Label.Text = '';
            app.XOver_km = uieditfield(app.GlodapCrossoverkmPanel, 'numeric');
            app.XOver_km.Limits = [1 Inf];
            app.XOver_km.ValueDisplayFormat = '%.0f';
            app.XOver_km.ValueChangedFcn = createCallbackFcn(app, @XOver_kmValueChanged, true);
            app.XOver_km.Value = 30;
            app.PlotTypeButtonGroup = uibuttongroup(app.SAGEV2Panel);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.ProfileButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ProfileButton.Text = 'Profile';
            app.SurfaceButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.SurfaceButton.Text = 'Surface';
            app.DeepButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.DeepButton.Text = 'Deep';
            app.DeepButton.Value = true;
            app.ReferenceDataButtonGroup = uibuttongroup(app.SAGEV2Panel);
            app.ReferenceDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ReferenceDataButtonGroupSelectionChanged, true);
            app.ReferenceDataButtonGroup.Title = 'Reference Data';
            app.ESPER_MIX_NoO2Button = uiradiobutton(app.ReferenceDataButtonGroup);
            app.ESPER_MIX_NoO2Button.Text = 'ESPER_MIX_NoO2';
            app.WOAButton = uiradiobutton(app.ReferenceDataButtonGroup);
            app.WOAButton.Text = 'WOA';
            app.CANYONBButton = uiradiobutton(app.ReferenceDataButtonGroup);
            app.CANYONBButton.Text = 'CANYONB';
            app.ESPER_LIRButton = uiradiobutton(app.ReferenceDataButtonGroup);
            app.ESPER_LIRButton.Text = 'ESPER_LIR';
            app.ESPER_NNButton = uiradiobutton(app.ReferenceDataButtonGroup);
            app.ESPER_NNButton.Text = 'ESPER_NN';
            app.ESPER_MIXButton = uiradiobutton(app.ReferenceDataButtonGroup);
            app.ESPER_MIXButton.Text = 'ESPER_MIX';
            app.ESPER_MIXButton.Value = true;
            app.ParameterButtonGroup = uibuttongroup(app.SAGEV2Panel);
            app.ParameterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ParameterButtonGroupSelectionChanged, true);
            app.ParameterButtonGroup.Title = 'Parameter';
            app.SalinityButton = uiradiobutton(app.ParameterButtonGroup);
            app.SalinityButton.Text = 'Salinity';
            app.TemperatureButton = uiradiobutton(app.ParameterButtonGroup);
            app.TemperatureButton.Text = 'Temperature';
            app.OxygenButton = uiradiobutton(app.ParameterButtonGroup);
            app.OxygenButton.Text = 'Oxygen';
            app.pHButton = uiradiobutton(app.ParameterButtonGroup);
            app.pHButton.Text = 'pH';
            app.NitrateButton = uiradiobutton(app.ParameterButtonGroup);
            app.NitrateButton.Text = 'Nitrate';
            app.NitrateButton.Value = true;
            app.DataAdjustmentsPanel = uipanel(app.SAGEV2Panel);
            app.DataAdjustmentsPanel.Title = 'Data Adjustments';
            app.QCATable = uitable(app.DataAdjustmentsPanel);
            app.QCATable.ColumnName = {'Cycle'; 'Gain'; 'Offset'; 'Drift'};
            app.QCATable.RowName = {};
            app.QCATable.ColumnEditable = true;
            app.QCATable.CellEditCallback = createCallbackFcn(app, @QCATableCellEdit, true);
            app.FindChangePointsButton = uibutton(app.DataAdjustmentsPanel, 'push');
            app.FindChangePointsButton.ButtonPushedFcn = createCallbackFcn(app, @FindChangePointsButtonPushed, true);
            app.FindChangePointsButton.Tooltip = {'Find optimum number of change points with a default gain = 1 and apply corrections'};
            app.FindChangePointsButton.Text = {'Find Change'; 'Points'};
            app.CalculateAdjustmentsButton = uibutton(app.DataAdjustmentsPanel, 'push');
            app.CalculateAdjustmentsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateAdjustmentsButtonPushed, true);
            app.CalculateAdjustmentsButton.Tooltip = {'Determine and apply corrections based on cycle nodes and gain in the table'};
            app.CalculateAdjustmentsButton.Text = {'Calculate'; 'Adjustments'};
            app.ApplyCelleditsButton = uibutton(app.DataAdjustmentsPanel, 'push');
            app.ApplyCelleditsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyCelleditsButtonPushed, true);
            app.ApplyCelleditsButton.Tooltip = {'Apply manual changes made to table.'};
            app.ApplyCelleditsButton.Text = {'Apply'; 'Cell edits'};
            app.ReloadinitialParameterdataButton = uibutton(app.SAGEV2Panel, 'push');
            app.ReloadinitialParameterdataButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadinitialParameterdataButtonPushed, true);
            app.ReloadinitialParameterdataButton.Tooltip = {'Reload original CC data for selected paremeter'};
            app.ReloadinitialParameterdataButton.Text = 'Reload initial Parameter data';
            app.ShowmapButton = uibutton(app.SAGEV2Panel, 'push');
            app.ShowmapButton.ButtonPushedFcn = createCallbackFcn(app, @ShowmapButtonPushed, true);
            app.ShowmapButton.Text = 'Show map';
            app.InsertRowButton = uibutton(app.SAGEV2Panel, 'push');
            app.InsertRowButton.ButtonPushedFcn = createCallbackFcn(app, @InsertRowButtonPushed, true);
            app.InsertRowButton.Tooltip = {'Insert row after 1st selected cell or at end'};
            app.InsertRowButton.Text = 'Insert Row';
            app.DeleteRowButton = uibutton(app.SAGEV2Panel, 'push');
            app.DeleteRowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButtonPushed, true);
            app.DeleteRowButton.Tooltip = {'Delelet rows corresponding to selected cells or at end'};
            app.DeleteRowButton.Text = 'Delete Row';
            app.pHCpOffsetCorrectionButtonGroup = uibuttongroup(app.SAGEV2Panel);
            app.pHCpOffsetCorrectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @pHCpOffsetCorrectionButtonGroupSelectionChanged, true);
            app.pHCpOffsetCorrectionButtonGroup.Tooltip = {'Allows the ability to correct the pH  pump offset when the float transitions into Cp mode. Corrections are applied ABOVE the CpAct pressure'};
            app.pHCpOffsetCorrectionButtonGroup.Title = 'pH Cp Offset Correction';
            app.mixedButton = uiradiobutton(app.pHCpOffsetCorrectionButtonGroup);
            app.mixedButton.Tooltip = {'Apply an offset with is an average of both approaches'};
            app.mixedButton.Text = 'mixed';
            app.linearButton = uiradiobutton(app.pHCpOffsetCorrectionButtonGroup);
            app.linearButton.Tooltip = {'Apply an offset based on linear extrapolation'};
            app.linearButton.Text = 'linear';
            app.polyButton = uiradiobutton(app.pHCpOffsetCorrectionButtonGroup);
            app.polyButton.Tooltip = {'Apply pH offset based on an iterative polynomial estimate '};
            app.polyButton.Text = 'poly';
            app.noneButton = uiradiobutton(app.pHCpOffsetCorrectionButtonGroup);
            app.noneButton.Tooltip = {'No pH offset correction will be applied'};
            app.noneButton.Text = 'none';
            app.noneButton.Value = true;
            app.Panel = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.Panel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.RAWTab = uitab(app.TabGroup);
            app.RAWTab.SizeChangedFcn = createCallbackFcn(app, @QCTabSizeChanged, true);
            app.RAWTab.Title = 'RAW';
            app.UIAxes_Hidden4CBRAW = uiaxes(app.RAWTab);
            app.UIAxes_Hidden4CBRAW.Visible = 'off';
            app.UIAxes_RAWSdnAnom = uiaxes(app.RAWTab);
            app.UIAxes_RAWCycleAnom = uiaxes(app.RAWTab);
            app.UIAxes_RAWSdnData = uiaxes(app.RAWTab);
            app.UIAxes_RAWCycleData = uiaxes(app.RAWTab);
            app.QCTab = uitab(app.TabGroup);
            app.QCTab.SizeChangedFcn = createCallbackFcn(app, @QCTabSizeChanged, true);
            app.QCTab.Title = 'QC';
            app.UIAxes_Hidden4CBQC = uiaxes(app.QCTab);
            app.UIAxes_Hidden4CBQC.Visible = 'off';
            app.UIAxes_QCSdnAnom = uiaxes(app.QCTab);
            app.UIAxes_QCCycleAnom = uiaxes(app.QCTab);
            app.UIAxes_QCSdnData = uiaxes(app.QCTab);
            app.UIAxes_QCCycleData = uiaxes(app.QCTab);
            app.UIAxes_QCCycleData.ButtonDownFcn = createCallbackFcn(app, @UIAxes_QCCycleDataButtonDown, true);
            app.pHpumpoffsetTab = uitab(app.TabGroup);
            app.pHpumpoffsetTab.SizeChangedFcn = createCallbackFcn(app, @pHpumpoffsetTabSizeChanged, true);
            app.pHpumpoffsetTab.Title = 'pH pump offset';
            app.UIAxes_Hidden4CB = uiaxes(app.pHpumpoffsetTab);
            app.UIAxes_Hidden4CB.XTick = [0 1];
            app.UIAxes_Hidden4CB.XTickLabel = '';
            app.UIAxes_Hidden4CB.YTick = [0 1];
            app.UIAxes_Hidden4CB.YTickLabel = '';
            app.UIAxes_Hidden4CB.Visible = 'off';
            app.UIAxes_ProfPOCor = uiaxes(app.pHpumpoffsetTab);
            title(app.UIAxes_ProfPOCor, 'Pump offset corrected raw pH')
            xlabel(app.UIAxes_ProfPOCor, 'pH')
            zlabel(app.UIAxes_ProfPOCor, 'Z')
            app.UIAxes_ProfPOCor.YDir = 'reverse';
            app.UIAxes_ProfNoCor = uiaxes(app.pHpumpoffsetTab);
            title(app.UIAxes_ProfNoCor, 'Raw pH')
            xlabel(app.UIAxes_ProfNoCor, 'pH')
            ylabel(app.UIAxes_ProfNoCor, 'Pressure')
            zlabel(app.UIAxes_ProfNoCor, 'Z')
            app.UIAxes_ProfNoCor.YDir = 'reverse';
            app.UIAxes_CyclePO = uiaxes(app.pHpumpoffsetTab);
            xlabel(app.UIAxes_CyclePO, 'cycle')
            ylabel(app.UIAxes_CyclePO, 'pH pump offset')
            zlabel(app.UIAxes_CyclePO, 'Z')
            app.SelectfileButton = uibutton(app.Panel, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Tooltip = {'Click to load float'};
            app.SelectfileButton.Text = 'Select file';
            app.SHOWQCHISTORYButton = uibutton(app.Panel, 'push');
            app.SHOWQCHISTORYButton.ButtonPushedFcn = createCallbackFcn(app, @SHOWQCHISTORYButtonPushed, true);
            app.SHOWQCHISTORYButton.Text = 'SHOW QC HISTORY';
            app.UPDATEQCLISTFILEButton = uibutton(app.Panel, 'push');
            app.UPDATEQCLISTFILEButton.ButtonPushedFcn = createCallbackFcn(app, @UPDATEQCLISTFILEButtonPushed, true);
            app.UPDATEQCLISTFILEButton.Text = 'UPDATE QC LIST FILE';
            app.UPDATEMBARIODVButton = uibutton(app.Panel, 'push');
            app.UPDATEMBARIODVButton.ButtonPushedFcn = createCallbackFcn(app, @UPDATEMBARIODVButtonPushed, true);
            app.UPDATEMBARIODVButton.Text = 'UPDATE MBARI ODV';
            app.UPDATELOCALBFILESButton = uibutton(app.Panel, 'push');
            app.UPDATELOCALBFILESButton.Text = 'UPDATE LOCAL BFILES';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAGEv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end